classdef GraFT2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        Label2                         matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        NewLoadTab                     matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        Panel_2                        matlab.ui.container.Panel
        GridLayout67                   matlab.ui.container.GridLayout
        GridLayout69                   matlab.ui.container.GridLayout
        NewLoadButton                  matlab.ui.control.Button
        WelcometoGraFTSelectfiletypebelowtobeginLabel  matlab.ui.control.Label
        GridLayout68                   matlab.ui.container.GridLayout
        PATHLabel                      matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        LoadDataPanel                  matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        SelectFileTypePanel            matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        GRAFTButton                    matlab.ui.control.StateButton
        RemotedataLabel                matlab.ui.control.Label
        GRAFTObjectLabel               matlab.ui.control.Label
        IndividualfileLabel            matlab.ui.control.Label
        LocalfolderLabel               matlab.ui.control.Label
        S3Button                       matlab.ui.control.StateButton
        FileButton                     matlab.ui.control.StateButton
        FolderButton                   matlab.ui.control.StateButton
        Panel_5                        matlab.ui.container.Panel
        GridLayout10                   matlab.ui.container.GridLayout
        ShiftClickGroupSelectCtrlClickMultiSelectLabel  matlab.ui.control.Label
        GridLayout11                   matlab.ui.container.GridLayout
        ALLButton                      matlab.ui.control.Button
        SelectButton                   matlab.ui.control.Button
        ListBox                        matlab.ui.control.ListBox
        TextArea                       matlab.ui.control.TextArea
        PreprocessTab                  matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        ViewModePanel                  matlab.ui.container.Panel
        GridLayout17                   matlab.ui.container.GridLayout
        GridLayout53                   matlab.ui.container.GridLayout
        UIAxes2                        matlab.ui.control.UIAxes
        GridLayout19                   matlab.ui.container.GridLayout
        GridLayout55                   matlab.ui.container.GridLayout
        GridLayout56                   matlab.ui.container.GridLayout
        Button_2                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        TextArea_2                     matlab.ui.control.TextArea
        GridLayout21                   matlab.ui.container.GridLayout
        EnlargedmeanimagestackprojectionLabel  matlab.ui.control.Label
        ProjectionButton               matlab.ui.control.Button
        ContrasttoolviewingonlyLabel   matlab.ui.control.Label
        HistogramButton                matlab.ui.control.Button
        SingleViewButton               matlab.ui.control.Button
        IndividualimageviewLabel       matlab.ui.control.Label
        GridLayout20                   matlab.ui.container.GridLayout
        ResetButton_2                  matlab.ui.control.Button
        ResettoOriginalLabel           matlab.ui.control.Label
        NewLoadLabel                   matlab.ui.control.Label
        DetectButton                   matlab.ui.control.StateButton
        GridLayout16                   matlab.ui.container.GridLayout
        TabGroup3                      matlab.ui.container.TabGroup
        MotionCorrectionTab            matlab.ui.container.Tab
        GridLayout25                   matlab.ui.container.GridLayout
        GridLayout26                   matlab.ui.container.GridLayout
        GridLayout27                   matlab.ui.container.GridLayout
        MotionCorrectButton            matlab.ui.control.Button
        MethodDropDown                 matlab.ui.control.DropDown
        MethodDropDownLabel            matlab.ui.control.Label
        Available1NoRMCorreNonRigitMotionCorrectionLabel  matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        CropTab                        matlab.ui.container.Tab
        GridLayout22                   matlab.ui.container.GridLayout
        GridLayout28                   matlab.ui.container.GridLayout
        GridLayout30                   matlab.ui.container.GridLayout
        CancelButton                   matlab.ui.control.Button
        ApplyButton                    matlab.ui.control.Button
        GridLayout29                   matlab.ui.container.GridLayout
        Label_3                        matlab.ui.control.Label
        SelectAreaButton               matlab.ui.control.Button
        InteractiveselectionofareasquareregiontocropLabel  matlab.ui.control.Label
        MaskTab                        matlab.ui.container.Tab
        GridLayout22_2                 matlab.ui.container.GridLayout
        AddinsertselectprecomputedmaskoptionsLabel  matlab.ui.control.Label
        GridLayout28_2                 matlab.ui.container.GridLayout
        GridLayout42                   matlab.ui.container.GridLayout
        GridLayout43                   matlab.ui.container.GridLayout
        ViewMaskButton                 matlab.ui.control.Button
        ClearButton                    matlab.ui.control.Button
        ApplyButton_2                  matlab.ui.control.Button
        ColorButton                    matlab.ui.control.Button
        GridLayout52                   matlab.ui.container.GridLayout
        Label_4                        matlab.ui.control.Label
        GridLayout41                   matlab.ui.container.GridLayout
        PreComputedDropDown            matlab.ui.control.DropDown
        PreComputedDropDownLabel       matlab.ui.control.Label
        SelfLoadButton                 matlab.ui.control.Button
        LoadPARAMSmaskLabel            matlab.ui.control.Label
        CreateButton                   matlab.ui.control.Button
        SelectAreaButton_2             matlab.ui.control.Button
        FreehandSelectionsLabel        matlab.ui.control.Label
        WaveletDenoisingTab            matlab.ui.container.Tab
        GridLayout34                   matlab.ui.container.GridLayout
        GridLayout26_2                 matlab.ui.container.GridLayout
        GridLayout38                   matlab.ui.container.GridLayout
        AdvancedButton                 matlab.ui.control.StateButton
        DenoiseButton                  matlab.ui.control.Button
        GridLayout37                   matlab.ui.container.GridLayout
        ThresholdRuleDropDown          matlab.ui.control.DropDown
        ThresholdRuleDropDownLabel     matlab.ui.control.Label
        NoiseEstimateDropDown          matlab.ui.control.DropDown
        NoiseEstimateDropDownLabel     matlab.ui.control.Label
        LevelSpinner                   matlab.ui.control.Spinner
        LevelSpinnerLabel              matlab.ui.control.Label
        OrderEditField                 matlab.ui.control.NumericEditField
        OrderEditFieldLabel            matlab.ui.control.Label
        WaveletDropDown                matlab.ui.control.DropDown
        WaveletDropDownLabel           matlab.ui.control.Label
        DenoisingMethodDropDown        matlab.ui.control.DropDown
        DenoisingMethodDropDownLabel   matlab.ui.control.Label
        TextArea_3                     matlab.ui.control.TextArea
        DenosingLabel                  matlab.ui.control.Label
        SpatialFilteringTab            matlab.ui.container.Tab
        GridLayout36                   matlab.ui.container.GridLayout
        GridLayout54                   matlab.ui.container.GridLayout
        Label_5                        matlab.ui.control.Label
        BaselineCorrectionTab          matlab.ui.container.Tab
        GridLayout44                   matlab.ui.container.GridLayout
        Label_6                        matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        ParametersTab                  matlab.ui.container.Tab
        GridLayout12                   matlab.ui.container.GridLayout
        Panel_6                        matlab.ui.container.Panel
        GridLayout47                   matlab.ui.container.GridLayout
        GridLayout63                   matlab.ui.container.GridLayout
        GraFTConstructionPanel         matlab.ui.container.Panel
        GridLayout59                   matlab.ui.container.GridLayout
        nonnegativetemporalcomponentsDropDown  matlab.ui.control.DropDown
        nonnegativetemporalcomponentsDropDownLabel  matlab.ui.control.Label
        numberofcomponentsEditField    matlab.ui.control.NumericEditField
        numberofcomponentsEditFieldLabel  matlab.ui.control.Label
        patchsizeEditField             matlab.ui.control.NumericEditField
        usepatchGraFTDropDown          matlab.ui.control.DropDown
        usepatchGraFTDropDownLabel     matlab.ui.control.Label
        patchsizeEditFieldLabel        matlab.ui.control.Label
        nonnegativespatialcoefficientsDropDownLabel  matlab.ui.control.Label
        nonnegativespatialcoefficientsDropDown  matlab.ui.control.DropDown
        DataSizeAdjustmentsPanel       matlab.ui.container.Panel
        GridLayout64                   matlab.ui.container.GridLayout
        GridLayout65                   matlab.ui.container.GridLayout
        UseCheckBox                    matlab.ui.control.CheckBox
        DetectButton_2                 matlab.ui.control.Button
        TextArea_5                     matlab.ui.control.TextArea
        GridLayout60                   matlab.ui.container.GridLayout
        AdvancedPanel                  matlab.ui.container.Panel
        GridLayout61                   matlab.ui.container.GridLayout
        continuityregularizationEditField  matlab.ui.control.NumericEditField
        continuityregularizationEditFieldLabel  matlab.ui.control.Label
        continuitystepsEditField       matlab.ui.control.NumericEditField
        continuitystepsEditFieldLabel  matlab.ui.control.Label
        numberofRWL1GFrepsEditField    matlab.ui.control.NumericEditField
        numberofRWL1GFrepsEditFieldLabel  matlab.ui.control.Label
        betaEditField                  matlab.ui.control.NumericEditField
        betaEditFieldLabel             matlab.ui.control.Label
        tauEditField                   matlab.ui.control.NumericEditField
        tauEditFieldLabel              matlab.ui.control.Label
        OptimizationPanel              matlab.ui.container.Panel
        GridLayout57                   matlab.ui.container.GridLayout
        temporalcorrelationregularizationEditField  matlab.ui.control.NumericEditField
        temporalcorrelationregularizationEditFieldLabel  matlab.ui.control.Label
        componentregularizationEditField  matlab.ui.control.NumericEditField
        componentregularizationEditFieldLabel  matlab.ui.control.Label
        sparsityconstraintEditField    matlab.ui.control.NumericEditField
        sparsityconstraintEditFieldLabel  matlab.ui.control.Label
        OptionsPanel                   matlab.ui.container.Panel
        GridLayout48                   matlab.ui.container.GridLayout
        GridLayout49                   matlab.ui.container.GridLayout
        GridLayout58                   matlab.ui.container.GridLayout
        ResettodefaultvaluesLabel      matlab.ui.control.Label
        ResetButton_3                  matlab.ui.control.Button
        GridLayout51                   matlab.ui.container.GridLayout
        NameEditField                  matlab.ui.control.EditField
        NameEditFieldLabel             matlab.ui.control.Label
        SavecurrentPARAMSLabel         matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        GridLayout50                   matlab.ui.container.GridLayout
        LoadButton                     matlab.ui.control.Button
        LoadaPARAMSfileLabel           matlab.ui.control.Label
        TextArea_4                     matlab.ui.control.TextArea
        GraFTTab                       matlab.ui.container.Tab
        GridLayout8                    matlab.ui.container.GridLayout
        TabGroup2                      matlab.ui.container.TabGroup
        RunTab                         matlab.ui.container.Tab
        GridLayout14                   matlab.ui.container.GridLayout
        TextArea_6                     matlab.ui.control.TextArea
        Panel_7                        matlab.ui.container.Panel
        GridLayout71                   matlab.ui.container.GridLayout
        GridLayout76                   matlab.ui.container.GridLayout
        AuthorEditField                matlab.ui.control.EditField
        AuthorEditFieldLabel           matlab.ui.control.Label
        GridLayout75                   matlab.ui.container.GridLayout
        StudyEditField                 matlab.ui.control.EditField
        StudyEditFieldLabel            matlab.ui.control.Label
        GridLayout73                   matlab.ui.container.GridLayout
        STARTButton                    matlab.ui.control.Button
        RUNGRAFTLabel                  matlab.ui.control.Label
        GridLayout72                   matlab.ui.container.GridLayout
        ViewcurrentPARAMSloadedLabel   matlab.ui.control.Label
        CheckPARAMSButton              matlab.ui.control.Button
        ResultsTab                     matlab.ui.container.Tab
        GridLayout84                   matlab.ui.container.GridLayout
        GridLayout86                   matlab.ui.container.GridLayout
        GridLayout87                   matlab.ui.container.GridLayout
        xxLabel                        matlab.ui.control.Label
        Button_5                       matlab.ui.control.Button
        Button_4                       matlab.ui.control.Button
        TextArea_7                     matlab.ui.control.TextArea
        UIAxes4                        matlab.ui.control.UIAxes
        GridLayout85                   matlab.ui.container.GridLayout
        GridLayout88                   matlab.ui.container.GridLayout
        ColormapDropDown               matlab.ui.control.DropDown
        ColormapDropDownLabel          matlab.ui.control.Label
        Panel_8                        matlab.ui.container.Panel
        Panel_9                        matlab.ui.container.Panel
        UIAxes4_2                      matlab.ui.control.UIAxes
        AnalysisTab_2                  matlab.ui.container.Tab
        GridLayout83                   matlab.ui.container.GridLayout
        TabGroup4                      matlab.ui.container.TabGroup
        ROIsTab                        matlab.ui.container.Tab
        GridLayout89                   matlab.ui.container.GridLayout
        GridLayout91                   matlab.ui.container.GridLayout
        GridLayout95                   matlab.ui.container.GridLayout
        DetectButton_3                 matlab.ui.control.Button
        GridLayout94                   matlab.ui.container.GridLayout
        GridLayout96                   matlab.ui.container.GridLayout
        UITable                        matlab.ui.control.Table
        ListBox_2                      matlab.ui.control.ListBox
        UIAxes5_3                      matlab.ui.control.UIAxes
        GridLayout93                   matlab.ui.container.GridLayout
        TemporalViewModeDropDown       matlab.ui.control.DropDown
        TemporalViewModeDropDownLabel  matlab.ui.control.Label
        UIAxes5_4                      matlab.ui.control.UIAxes
        GridLayout90                   matlab.ui.container.GridLayout
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes5_2                      matlab.ui.control.UIAxes
        SpatialTab                     matlab.ui.container.Tab
        GridLayout15                   matlab.ui.container.GridLayout
        GridLayout82                   matlab.ui.container.GridLayout
        SaveCurrentVideoButton         matlab.ui.control.Button
        frametostartEditField          matlab.ui.control.NumericEditField
        frametostartEditFieldLabel     matlab.ui.control.Label
        fpsEditField                   matlab.ui.control.NumericEditField
        fpsEditFieldLabel              matlab.ui.control.Label
        SelectPlotstoViewLabel         matlab.ui.control.Label
        AbsDifferenceCheckBox          matlab.ui.control.CheckBox
        GraFTDictionaryLearningCheckBox  matlab.ui.control.CheckBox
        OriginalCheckBox               matlab.ui.control.CheckBox
        LoadButton_2                   matlab.ui.control.Button
        GridLayout80                   matlab.ui.container.GridLayout
        ABSDIFFERENCEPanel             matlab.ui.container.Panel
        GridLayout81_3                 matlab.ui.container.GridLayout
        UIAxes3_3                      matlab.ui.control.UIAxes
        GRAFTRECONSTRUCTEDPanel        matlab.ui.container.Panel
        GridLayout81_2                 matlab.ui.container.GridLayout
        UIAxes3_2                      matlab.ui.control.UIAxes
        ORIGINALPanel                  matlab.ui.container.Panel
        GridLayout81                   matlab.ui.container.GridLayout
        UIAxes3                        matlab.ui.control.UIAxes
        GridLayout79                   matlab.ui.container.GridLayout
        PlayButton                     matlab.ui.control.Button
        Slider                         matlab.ui.control.Slider
        SettingsTab                    matlab.ui.container.Tab
        GridLayout9                    matlab.ui.container.GridLayout
        GridLayout78                   matlab.ui.container.GridLayout
        ResetDefaultButton             matlab.ui.control.Button
        SaveButton_2                   matlab.ui.control.Button
        OptionsPanel_2                 matlab.ui.container.Panel
        ParallelProcessingPanel        matlab.ui.container.Panel
        AppearancePanel                matlab.ui.container.Panel
        GridLayout77                   matlab.ui.container.GridLayout
        FontScaleEditField             matlab.ui.control.NumericEditField
        FontScaleEditFieldLabel        matlab.ui.control.Label
        ColorModeDropDown              matlab.ui.control.DropDown
        ColorModeDropDownLabel         matlab.ui.control.Label
        AboutTab                       matlab.ui.container.Tab
        GridLayout92                   matlab.ui.container.GridLayout
        GraFTAppLabel                  matlab.ui.control.Label
    end

    
    properties (Access = public)
        fset        % app.uifigure settings
        lDir        % launch directory
        G           % GRAFT
        Status      % current app behaviour / control
    end
    
    methods (Access = private)
        
        function setUIUX(app)
            % get user settings
            temp = userSettings();
            app.fset = temp.defaultSettings;
            app.ColorModeDropDown.Value = app.fset.appearance_color;
            app.FontScaleEditField.Value = app.fset.fontScale;

            % background colors
            app = colorMe(app, 'DarkMode');

            % default visibilities
            app = setVis(app);
            
            % font settings
            app.fset.fontHandles = findobj(app.UIFigure, '-property', 'FontSize');
            app.fset.fontSizes = cell2mat(get(app.fset.fontHandles, 'FontSize'));
        end
        
        function app = setDefault(app)
            % Dir
            app.lDir = pwd;
            app.Label.Text = app.lDir;
            
            % status
            app.Status = setStatus();

            % GRAFT
            app.G = GRAFT();

            % PARAMETERS
            app = setappParams(app, "");

            % Default Pre-Process
            app = setPreProcess(app);
        end
        
        function mouse_click(app, ~, hit)
            coords = ceil(hit.IntersectionPoint);
            if any(coords < 0) || any(coords > max(size(app.G.GraFTed.spatial,1:2)))
                warning('Coordinates outside of Axes. Try again')
                return
            end
            
            % find ROI in spot
            val = 0;
            idx = 0;
            for i = 1:size(app.G.GraFTed.spatial,3)
                if app.G.GraFTed.spatial(coords(2), coords(1), i) > val
                    val = app.G.GraFTed.spatial(coords(2), coords(1), i);
                    idx = i;
                end
            end

            % get ROI index
            if idx == 0
                return
            else
                imagesc(app.G.GraFTed.spatial(:,:,idx), 'Parent', app.UIAxes5_3)
                axis(app.UIAxes5_3, 'image')
                title(app.UIAxes5_3, sprintf('ROI-%d', idx))
                app.UIAxes5_3.Title.Color = 'w';
                colormap(app.UIAxes5_3, "gray")
                set(app.UIAxes5_3, 'YTick', [], 'XTick', []);
                % ListBox update
                app.ListBox_2.Value = sprintf('ROI %d', idx);
                % Temporal update
                plot(1:size(app.G.GraFTed.temporal, 1), app.G.GraFTed.plot_image(:,idx), 'Parent', app.UIAxes5_4);
                set(app.UIAxes5_4, 'Color', 'k', 'YTick', [], 'Xcolor', 'w', 'Ycolor', 'w');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % hide app figure
            app.UIFigure.Visible = 'off';
            % UI/UX
            app.setUIUX()
            start_width = app.UIFigure.Position(3);
            newFontSize = double(start_width)*app.fset.fontSizes/app.fset.fontScale;
            set(app.fset.fontHandles, {'FontSize'}, num2cell(newFontSize))
            % Default Load
            app = app.setDefault();
            % show app figure
            app.UIFigure.Visible = 'on';
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            % auto updates font sizes
            try
                new_width = app.UIFigure.Position(3);
                newFontSize = double(new_width)*app.fset.fontSizes/app.fset.fontScale;
                set(app.fset.fontHandles, {'FontSize'}, num2cell(newFontSize))
            catch 
                % first time run?
                app.fset.userResolution = get(0, 'ScreenPixelsPerInch');
                app.fset.userScrnSize   = get(0, 'ScreenSize');
            end
        end

        % Value changed function: FileButton, FolderButton, S3Button
        function FolderButtonValueChanged(app, event)
            % Search for either folder or individual file. Function is used
            % for all buttons in New Load Tab.
            
            % Double pressed = New Load
            try
                if event.Value
                    app.NewLoadButtonPushed()
                    return
                end
            catch
                % this is if called by other event w/o .Value property [mat
                % struct loading
            end
            
            % Check first time or cancel load
            if isequal(app.G.Encoding.data_path(1), 0)

                % Folder/File
                switch event.Source.Text
                    case 'Folder'
                        % Visibilities/Assignments
                        app.FileButton.Visible = 'off';
                        app.S3Button.Visible = 'off';
                        app.GRAFTButton.Visible = 'off';
                        app.G.Encoding.loading_type = 'Folder';
                        app.ListBox.Multiselect = 'on';
                        app.ALLButton.Enable = 'on';
                        % Get dir
                        path = uigetdir("Select Folder to Save");
                    case 'File'
                        % Visibilities/Assignments
                        app.FolderButton.Visible = 'off';
                        app.S3Button.Visible = 'off';
                        app.GRAFTButton.Visible = 'off';
                        app.G.Encoding.loading_type = 'File';
                        app.ListBox.Multiselect = 'off';
                        app.ALLButton.Enable = 'off';       % turn on if .tif
                        % Get file
                        [filename, path] = uigetfile({'*.h5;*.mat;*.tif'}, "Select File");
                        path = [path, filename];
                    case 'S3'
                        % TODO %
                        % Visibilities
                        app.FolderButton.Visible = 'off';
                        app.FileButton.Visible = 'off';
                        app.G.Encoding.loading_type = 'S3';
                end
    
                % Back Focus
                figure(app.UIFigure);
    
                % Encoding Update
                app.G.Encoding.data_path = path;
            end

            % Check if exists
            if ~isequal(app.G.Encoding.data_path(1), 0)
                % Extension
                [~, ~, extension] = fileparts(app.G.Encoding.data_path);
                % Update Text
                txt = sprintf(">>%s Selected\n", app.G.Encoding.loading_type);
                app.TextArea.Value = txt;
                % Search
                d = uiprogressdlg(app.UIFigure,'Title','Loading Selected',...
                    'Cancelable','off', 'Indeterminate','on','CancelText','Early Stop');
                d.Message = sprintf('File type - %s', extension);
                app.G = load_app_data2(app.G);
                close(d);

                % Populate ListBox
                if strcmp(app.G.Encoding.flag, "") || strcmp(app.G.Encoding.flag, "struct")
                    app.G.Encoding.flag = "";
                    app.ALLButton.Enable = 'on';
                    app.SelectButton.Enable = 'on';
                    app.ListBox.Items = app.G.Encoding.files.Name;
                else
                    app.TextArea.Value = sprintf("%s\n%s",app.TextArea.Value{:}, ...
                                                        app.G.Encoding.flag);
                end

                % Check ListBox Behaviour
                if strcmp(app.G.Encoding.loading_type, "File")
                    app.ListBox.Multiselect = 'off';
                    app.ALLButton.Enable = 'off';
                    if strcmp(extension, ".tif")
                        app.ALLButton.Enable = 'on';
                        app.ListBox.Multiselect = 'on';
                    end
                end
                % Enable new load
                app.NewLoadButton.Enable = 'on';
            else
                % if cancelled
                app.G.Encoding.loading_type = '';
                app.FolderButton.Visible = 'on';
                app.FileButton.Visible = 'on';
                app.S3Button.Visible = 'on';
                app.FolderButton.Value = 1;
                app.FileButton.Value = 1;
                app.S3Button.Value = 1;
                app.GRAFTButton.Visible = 'on';
                app.ALLButton.Enable = 'off';
                return
            end

        end

        % Button pushed function: NewLoadButton
        function NewLoadButtonPushed(app, event)
            % default visibilities
            app = setVis(app);
            % Default Load
            app.setDefault();
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            switch app.G.Encoding.loading_type
                case 'Folder'
                    if length(value)>1  % Multiselect
                        % Find rows with matching names
                        matchingRows = ismember(app.G.Encoding.files.Name, value);
                        
                        % Create a new table with matching rows
                        newTable = app.G.Encoding.files(matchingRows, :);
                        
                        % Check for uniformity in columns (excluding the 'Name' column)
                        uniformityFlag = true; % Assume uniformity initially
                        numColumns = size(newTable, 2) - 1; % Number of columns excluding 'Name'
                        nonUniformColumns = cell(1, numColumns); % Preallocate cell array
                        
                        % Iterate over each column (excluding the 'Name' column)
                        index = 1; % Index for nonUniformColumns
                        for colIndex = 1:size(newTable, 2)
                            if ~strcmp(newTable.Properties.VariableNames{colIndex}, 'Name') % Skip the 'Name' column
                                uniqueValues = unique(newTable.(colIndex));
                                if numel(uniqueValues) > 1
                                    uniformityFlag = false; % Set flag to indicate non-uniformity
                                    nonUniformColumns{index} = newTable.Properties.VariableNames{colIndex};
                                    index = index + 1;
                                end
                            end
                        end
                        
                        % Trim unused cells in nonUniformColumns
                        nonUniformColumns = nonUniformColumns(1:index-1);
                        
                        % Display warning if the selected rows are not uniform
                        if ~uniformityFlag
                            warningMsg = 'Warning: The selected rows are not uniform in the following column(s): ';
                            for i = 1:numel(nonUniformColumns)
                                warningMsg = [warningMsg, nonUniformColumns{i}, ', '];
                            end
                            warningMsg = warningMsg(1:end-2); % Remove the last comma and space
                            app.TextArea.Value = sprintf("%s",warningMsg);
                            % Disable Selection
                            app.SelectButton.Enable = 'off';
                        else
                            % Enable Selection
                            app.SelectButton.Enable = 'on';
                        end
                        
                    else % Single Select
                        row = app.G.Encoding.files(strcmp(app.G.Encoding.files.Name, value), :);
                        txt = sprintf("          name: %s\n" + ...
                            "                    format: %s\n" + ...
                            "                     bytes: %d\n " + ...
                            "                      size: [%dx%d]\n" + ...
                            "               colortype: %s\n", ...
                                row.Name{1}, row.Format{1}, row.FileSize(1), ...
                                row.Height(1), row.Width(1), row.ColorType{1});
                        app.TextArea.Value = sprintf('>>Folder Selected\n>>current_file\n           %s', txt);
                        % Enable Selection
                        app.SelectButton.Enable = 'on';
                    end

                case 'File'
                    % Extension
                    [~, ~, extension] = fileparts(app.G.Encoding.data_path);

                    % Case of 'select all' .tif file
                    if height(app.G.Encoding.files)>1 && strcmp(extension, '.tif')
                        app.TextArea.Value = sprintf(">>File Selected\n>>ALL");
                        return
                    end

                    % Find file
                    row = app.G.Encoding.files(strcmp(app.G.Encoding.files.Name, value), :);
                    switch extension
                        case '.tif'
                            % Update
                            txt = sprintf("          name: %s\n" + ...
                                    "                   filesize: %s\n" + ...
                                    "                       size: [%dx%d]\n" + ...
                                    "               colortype: %s\n", ...
                                        row.Name, row.FileSize, ...
                                        row.Height, row.Width, ...
                                        row.ColorType{:});
                            app.TextArea.Value = sprintf(['>>File Selected\n>>info\n' ...
                                                          '           %s'], txt);
                        case '.mat'
                            % Update
                            txt = sprintf("          name: %s\n" + ...
                                    "                       size: [%s]\n" + ...
                                    "                    class: %s\n", ...
                                        row.Name{:}, row.Size{:}, row.Class{:});
                            app.TextArea.Value = sprintf(['>>File Selected\n>>info\n' ...
                                                          '           %s'], txt);
                        case '.h5'
                            % Update
                            txt = sprintf("          name: %s\n" + ...
                                    "                       type: %s\n" + ...
                                    "                       size: %s\n" + ...
                                    "                datasets: %s\n", ...
                                        row.Name, ...
                                        row.Type, ...
                                        row.Size, ...
                                        row.Datasets);
                            app.TextArea.Value = sprintf(['>>File Selected\n>>info\n' ...
                                                          '           %s'], txt);
                    end
            end
        end

        % Button pushed function: ALLButton
        function ALLButtonPushed(app, event)
            % Select All
            app.ListBox.Value = app.ListBox.Items;
            app.ListBoxValueChanged(app)
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            % Load Selection
            value = app.ListBox.Value;
            % Initialize 
            out = [];

            %% Check Selection
            % Empty Selection
            if isempty(value)
                new_msg = sprintf(">>No value selected. Try again.");
                app.TextArea.Value = vertcat(app.TextArea.Value, new_msg);
            end

            % Repetitive Selection
            if isequal(value, app.G.Encoding.last_val_load)
                new_msg = sprintf(">>Variable already loaded.\n" + ...
                                  ">>Continue to other tabs for further analysis.");
                app.TextArea.Value = vertcat(app.TextArea.Value, new_msg);
                return
            end

            %% New Table
            % Find rows with matching names
            matchingRows = ismember(app.G.Encoding.files.Name, value);
            % Create a new table with matching rows
            newTable = app.G.Encoding.files(matchingRows, :);
            
            % UIprogressdlg
            d = uiprogressdlg(app.UIFigure,'Title','Loading Selected',...
            'Cancelable','off', 'Indeterminate','on','CancelText','Early Stop');

            %% FILE / FOLDER SELECT
            switch app.G.Encoding.loading_type
                case 'File'
                    [out, app.G.Encoding] = selectFile(app.G.Encoding, newTable);
                case 'Folder'
                    out = selectFolder(app.G, newTable);
            end

            % Flag check
            if strcmpi(app.G.Encoding.flag, "struct")
                app.FolderButtonValueChanged
                app.ListBoxValueChanged
                return
            end

            %% Out
            % Close UIProgress
            close(d);

            % Update Selection
            app.G.Data.original = out;
            app.G.Data.original_size = size(out);
            app.G.Encoding.last_val_load = value;

            % Update TextArea
            new_msg = sprintf(">>Load successful.\n" + ...
                              ">>Continue to other tabs for further analysis.");
            app.TextArea.Value = vertcat(app.TextArea.Value, new_msg);
        end

        % Value changed function: DetectButton
        function DetectButtonValueChanged(app, event)
            value = app.DetectButton.Value;
            if value && ~isempty(app.G.Data.original)
                % UIprogressdlg
                d = uiprogressdlg(app.UIFigure,'Title','Loading',...
                    'Cancelable','off', 'Indeterminate','on');
                % Mean Image Stack Projection
                if isempty(app.G.Data.projection)
                    app.G.Data.projection = mean(app.G.Data.original,3);
                end
                
                % Update Plots
                cla(app.UIAxes, "reset"); cla(app.UIAxes2, "reset");
                % mean image stack
                imagesc(app.G.Data.projection, 'Parent', app.UIAxes2);
                set(app.UIAxes2, 'XTick', [], 'YTick', [])
                % large default plot
                imagesc(app.G.Data.original(:,:,1), 'Parent', app.UIAxes);
                % colormap
                colormap(app.UIAxes, gray); colormap(app.UIAxes2, gray);
                % Close UIprogress
                close(d);

                % Default Button Behaviour
                if size(size(app.G.Data.original), 2) == 3
                    app.Button_2.Enable = 'on';
                else
                    app.Status.view_idx = 0; 
                end
                
                % Logs
                if strcmp(app.TextArea_2.Value, ">>")
                    t = string(datetime("now", "Format","HH:mm:ss"));
                    msg = sprintf("%s >> Comments and Logs", t);
                    app.TextArea_2.Value = msg; 
                end 

                % Visibilities
                app.UIAxes.Visible = 'on';
                app.GridLayout21.Visible = 'on';
                app.GridLayout16.Visible = 'on';
                app.GridLayout53.Visible = 'on';
                app.GridLayout55.Visible = 'on';
                app.NewLoadLabel.Enable = 'off';
                app.DetectButton.Enable = 'off';
            end

            % Visibilities (here for debug)
            app.UIAxes.Visible = 'on';
            app.GridLayout53.Visible = 'on';
            app.GridLayout21.Visible = 'on';
            app.GridLayout16.Visible = 'on';
            app.GridLayout55.Visible = 'on';
            app.NewLoadLabel.Enable = 'off';
            app.DetectButton.Enable = 'off';
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            app = plotUpdatePreprocess(app, "Reset");
            % Plots
            a = struct(); a.Source.Text = 'Projection';
            SingleViewButtonPushed(app, a);
        end

        % Button pushed function: ProjectionButton, SingleViewButton
        function SingleViewButtonPushed(app, event)
            switch event.Source.Text
                case 'Single View'
                    % Buttons
                    app.Button.Visible = 'on';
                    app.Button_2.Visible = 'on';
                    % UIAxes
                    cla(app.UIAxes, "reset");
                    if isempty(app.G.Data.pre_graft)
                        imagesc(app.G.Data.original(:,:,app.Status.view_idx),'Parent', app.UIAxes);
                    else
                        imagesc(app.G.Data.pre_graft(:,:,app.Status.view_idx),'Parent', app.UIAxes);
                    end
                    colormap(app.UIAxes, gray);

                case 'Projection'
                    % Buttons
                    app.Button.Visible = 'off';
                    app.Button_2.Visible = 'off';
                    % UIAxes
                    cla(app.UIAxes, "reset"); cla(app.UIAxes2, "reset");
                    imagesc(app.G.Data.projection, 'Parent', app.UIAxes);
                    imagesc(app.G.Data.projection, 'Parent', app.UIAxes2);
                    set(app.UIAxes2, 'XTick', [], 'YTick', [])
                    colormap(app.UIAxes, gray);colormap(app.UIAxes2, gray);
                case []
                    warning('problem with event.source selection @ singleviewbuttonpushed')
            end

            % Update Status
            app.Status.view_mode = event.Source.Text;

            % Reset
            if isempty(app.G.Data.pre_graft)
                app.ResettoOriginalLabel.Enable = 'off';
                app.ResetButton_2.Enable = 'off';
            else
                app.ResettoOriginalLabel.Enable = 'on';
                app.ResetButton_2.Enable = 'on';
            end
        end

        % Button down function: CropTab, MaskTab, WaveletDenoisingTab
        function CropTabButtonDown(app, event)
            % Alert for mask + crop
            n = event.Source.Title;
            
            f = false;
            switch n
                case 'Crop'
                    if ~isempty(app.G.Parameters.Parameters.mask)
                        f = true;
                    end

                case 'Mask'
                    if ~isempty(app.G.Parameters.Parameters.crop)
                        f = true;
                    end
            end
            

            if f
                msg = 'Crop and mask are mutually exclusive for proper GraFT function.';
                uialert(app.UIFigure, msg, 'Warning', 'Icon','warning', 'Modal',true); 
            end

            % Projection
            a = struct(); a.Source.Text = 'Projection';     % interesting work-around. change?
            SingleViewButtonPushed(app, a)
        end

        % Button pushed function: Button, Button_2
        function Button_2Pushed(app, event)
            switch event.Source.Text
                case '<'
                    if app.Status.view_idx > 1
                        app.Button_2.Enable = 'on';
                        app.Status.view_idx = app.Status.view_idx - 1;
                        if app.Status.view_idx == 1
                            app.Button.Enable = 'off';
                        end
                    end

                case '>'
                    if app.Status.view_idx < app.G.Data.original_size(3)
                        app.Button.Enable = 'on';
                        app.Status.view_idx = app.Status.view_idx + 1;
                        if app.Status.view_idx == app.G.Data.original_size(3)
                            app.Button_2.Enable = 'off';
                        end
                    end
            end
            
            % Plot
            if isempty(app.G.Data.pre_graft)
                imagesc(app.G.Data.original(:,:,app.Status.view_idx),'Parent', app.UIAxes);
            else
                imagesc(app.G.Data.pre_graft(:,:,app.Status.view_idx), 'Parent', app.UIAxes);
            end
            colormap(app.UIAxes, gray);
        end

        % Button pushed function: MotionCorrectButton
        function MotionCorrectButtonPushed(app, event)
            % method selected
            mc_method = app.MethodDropDown.Value;
            
            % UIprogressdlg
            d = uiprogressdlg(app.UIFigure,'Title','Loading',...
                    'Cancelable','off', 'Indeterminate','on');

            % Perform motion correction on original
            [app.G, app.Status] = motionCorrect(app.G, app.Status, mc_method);
            
            % Update Projection
            if ~app.Status.mcor_error
                app = plotUpdatePreprocess(app, "Motion Correction");
                % Plots
                a = struct(); a.Source.Text = 'Projection';
                SingleViewButtonPushed(app, a);
            else
                warning('Error during motion correction')
            end
            
            % Update Log
            t = string(datetime("now", "Format","HH:mm:ss"));
            msg = sprintf("%s >> Motion Correction Complete: %s", t, app.G.PreProcess.motco_method);
            app.TextArea_2.Value = sprintf([repmat('%s\n', 1, numel(app.TextArea_2.Value)) '%s\n'], ...
                                           app.TextArea_2.Value{:}, msg); 
            
            % Close UIprogress
            close(d);
        end

        % Button pushed function: SelectAreaButton
        function SelectAreaButtonPushed(app, event)
            % Disable crop button temporarily
            app.SelectAreaButton.Enable = 'off';

            % Check uiaxes
            if ~strcmpi(app.Status.view_mode, 'Projection')
                a = struct(); a.Source.Text = 'Projection';
                SingleViewButtonPushed(app, a);
            end
            
            % Craw roi
            app.G.Parameters.Parameters.crop = drawrectangle(app.UIAxes, 'Color', 'r');

            % Enable apply and cancel
            app.ApplyButton.Enable = 'on';
            app.CancelButton.Enable = 'on';
        end

        % Button pushed function: ApplyButton, CancelButton
        function ApplyButtonPushed(app, event)
            % Check view mode?

            switch event.Source.Text
                case 'Apply'
                    % Check aspect ratio for deformation                    -TODO

                    % Get indices [xmin, ymin, width, height]
                    idx = round(app.G.Parameters.Parameters.crop.Position);
                    col_crop = idx(1):idx(1)+idx(3);
                    row_crop = idx(2):idx(2)+idx(4);
                    
                    
                    % Crop
                    if isempty(app.G.Data.pre_graft)
                        app.G.Data.pre_graft = app.G.Data.original(row_crop, col_crop, :);
                    else
                        app.G.Data.pre_graft = app.G.Data.pre_graft(row_crop, col_crop, :);
                    end
                    
                    % Set crop settings
                    app.G.Parameters.Parameters.crop = idx;

                    % Update plots
                    if ~app.Status.mcor_error
                        app = plotUpdatePreprocess(app, "Crop");
                        % Plots
                        a = struct(); a.Source.Text = 'Projection';
                        SingleViewButtonPushed(app, a);
                    else
                        warning('Error during motion correction')
                    end

                    % Reset status view idx
                    app.Status.view_idx = 1;

                    % Update Status
                    app.Status.is_crop = true;

                    % Update Log
                    t = string(datetime("now", "Format","HH:mm:ss"));
                    msg = sprintf("%s >> Data cropped", t);
                    app.TextArea_2.Value = sprintf([repmat('%s\n', 1, numel(app.TextArea_2.Value)) '%s\n'], ...
                                           app.TextArea_2.Value{:}, msg);

                case 'Cancel'
                    % Remove crop roi
                    app.G.Parameters.Parameters.crop.delete
                    app.G.Parameters.Parameters.crop = [];

            end

            % Disable / enable buttons
            app.ApplyButton.Enable = 'off';
            app.CancelButton.Enable = 'off';
            app.SelectAreaButton.Enable = 'on';
        end

        % Button pushed function: CreateButton, SelectAreaButton_2, 
        % ...and 1 other component
        function SelectAreaButton_2Pushed(app, event)
            % Disable Buttons
            app.SelectAreaButton_2.Enable = 'off';
            app.SelfLoadButton.Enable = 'off';
            app.CreateButton.Enable = 'off';

            % Check uiaxes
            if ~strcmpi(app.Status.view_mode, 'Projection')
                a = struct(); a.Source.Text = 'Projection';
                SingleViewButtonPushed(app, a);
            end
        
            % Create Mask
            app = createMaskApp(app, event);

            % Back Focus
            figure(app.UIFigure);
            
            % View if load
            if strcmp(app.Status.mask_method, 'Self_Load')
                ViewMaskButtonPushed(app)
                app.SelectAreaButton_2.Enable = 'on';
                app.SelfLoadButton.Enable = 'on';
%                 app.CreateButton.Enable = 'on';
            end

            % Enable Apply/Cancel
            if ~isempty(app.G.Parameters.Parameters.mask)
                app.ClearButton.Enable = 'on';
            end
        end

        % Button pushed function: ColorButton
        function ColorButtonPushed(app, event)
            % Color
            c = uisetcolor('Select Color for Mask');
            % Update background and app status
            if ~isequal(c, 0)
                app.GridLayout52.BackgroundColor = c;
                app.Status.mask_color = c;
                % Update existing mask selection
                if isa(app.Status.mask_freehand, 'images.roi.Freehand') && ...
                    isvalid(app.Status.mask_freehand)
                    app.Status.mask_freehand.Color = c;
                end
            end
            % Back Focus
            figure(app.UIFigure);
        end

        % Button pushed function: ApplyButton_2, ClearButton
        function ApplyButton_2Pushed(app, event)
            switch event.Source.Text
                case 'Apply'
                    % check for mask existance/proper selection
                    logical_mask = createMask(app.Status.mask_freehand);
                    app.G.Parameters.Parameters.mask = addMask(app, logical_mask);
                    % show
                    temp = app.G.Data.projection;
                    temp(app.G.Parameters.Parameters.mask) = max(temp, [], 'all');
                    imagesc(app.UIAxes, temp);colormap('gray')
                    % remove last selection
                    app.Status.mask_freehand.delete;
                    % view mask + clear -> delete all
                    app.ViewMaskButton.Enable = "on";
                    app.ClearButton.Text = 'Delete Mask';
                    app.ClearButton.Enable = 'on';
                    % Update Log
                    t = string(datetime("now", "Format","HH:mm:ss"));
                    msg = sprintf("%s >> Mask created", t);
                    app.TextArea_2.Value = sprintf([repmat('%s\n', 1, numel(app.TextArea_2.Value)) '%s\n'], ...
                                           app.TextArea_2.Value{:}, msg); 

                case 'Clear'
                    if strcmp(app.Status.mask_method, 'Free_Hand')
                        % remove selection
                        app.Status.mask_freehand.delete;
                        if ~isempty(app.G.Parameters.Parameters.mask)
                            app.ClearButton.Text = 'Delete Mask';
                            app.ClearButton.Enable = 'on';
                        else
                            app.ClearButton.Enable = 'off';
                        end
                    end

                case 'Delete Mask'
                    % reset mask
                    app.G.Parameters.Parameters.mask = [];
                    % reset button
                    app.ClearButton.Text = 'Clear';
                    % disable view mask + clear
                    app.ViewMaskButton.Enable = 'off';
                    app.ClearButton.Enable = 'off';
                    % reset UIAxes
                    imagesc(app.UIAxes, app.G.Data.projection); colormap(app.UIAxes, 'gray')
                    % Update Log
                    t = string(datetime("now", "Format","HH:mm:ss"));
                    msg = sprintf("%s >> Mask deleted", t);
                    app.TextArea_2.Value = sprintf([repmat('%s\n', 1, numel(app.TextArea_2.Value)) '%s\n'], ...
                                           app.TextArea_2.Value{:}, msg);
            end

            % Disable apply 
            app.ApplyButton_2.Enable = 'off';

            % Remove color
            app.ColorButton.Visible = 'off';
            app.GridLayout52.Visible = 'off';

            % Enable Buttons
            app.SelectAreaButton_2.Enable = 'on';
            app.SelfLoadButton.Enable = 'on';
%             app.CreateButton.Enable = 'on';
        end

        % Button pushed function: ViewMaskButton
        function ViewMaskButtonPushed(app, event)
            app.Status.show_mask = app.Status.show_mask + 1;
            switch rem(app.Status.show_mask, 2)
                case 1
                    % hide mask
                    imagesc(app.UIAxes, app.G.Data.projection); colormap(app.UIAxes, 'gray')

                case 0
                    % show mask if exists
                    if ~isempty(app.G.Parameters.Parameters.mask)

                        % ensure view_mode
                        if ~strcmpi(app.Status.view_mode, 'Projection')
                            a = struct(); a.Source.Text = 'Projection';
                            SingleViewButtonPushed(app, a);
                        end
        
                        % merge
                        temp = app.G.Data.projection;
                        temp(app.G.Parameters.Parameters.mask) = max(temp, [], 'all');
        
                        % plot
                        imagesc(app.UIAxes, temp); colormap(app.UIAxes, 'gray')
                    end
            end
        end

        % Value changed function: AdvancedButton
        function AdvancedButtonValueChanged(app, event)
            value = app.AdvancedButton.Value;
            switch value
                case 1
                    app.TextArea_3.Visible = 'on';
                    app.GridLayout37.Visible = 'on';
                case 0
                    app.TextArea_3.Visible = 'off';
                    app.GridLayout37.Visible = 'off';
            end
        end

        % Button pushed function: DetectButton_2
        function DetectButton_2Pushed(app, event)
            if ~isempty(app.G.Parameters.Parameters.mask)
                m = numel(find(app.G.Parameters.Parameters.mask==1));
                a_size = size(app.G.Parameters.Parameters.mask);
                msg = sprintf(['>>MASK DETECTED\n' ...
                               '    Size: %dx%d\n'...
                               '    Pixels selected: %d\n'], ...
                               a_size, m);
            elseif ~isempty(app.G.Parameters.Parameters.crop)
                m = app.G.Parameters.Parameters.crop;
                a = app.G.Parameters.Parameters.crop(3) * ...
                    app.G.Parameters.Parameters.crop(4);
                msg = sprintf(">>CROP DETECTED\n" + ...
                              "     Rectangle: %dx%dx%dx%d\n" + ...
                              "     Area: %d", m, a);
            else
                msg = ">>No mask or crop detected.";
            end

            app.TextArea_5.Value = msg;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Load new PARAMS file
            loadParams(app);
            
            % Detect
            DetectButton_2Pushed(app);

            % Reset plots
            a = struct(); a.Source.Text = 'Projection';
            SingleViewButtonPushed(app, a);

            % Back Focus
            figure(app.UIFigure);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % save
            app = saveParams(app);

            name = string(app.NameEditField.Value);
            if isempty(name)
                % if empty
                n = app.G.Parameters.Parameters.n_dict;
                patch = app.G.Parameters.Parameters.pathGraFT;
                name = sprintf("ndict%d_pGraFT%s_run", n, string(patch));
            end

            % set name and save
            name = name + ".PARAMS.mat";
            var_to_save = app.G.Parameters;
            save(name, "var_to_save");
            
            % update
            t = string(datetime("now", "Format","HH:mm:ss"));
            msg = sprintf("%s >> Parameters saved in folder: %s", t, pwd);
            app.TextArea_4.Value = sprintf([repmat('%s\n', 1, numel(app.TextArea_2.Value)) '%s\n'], ...
            app.TextArea_4.Value{:}, msg);
        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
            % Reset params
            setappParams(app, 'Reset');
            % Reset plots
            a = struct(); a.Source.Text = 'Projection';
            SingleViewButtonPushed(app, a);

            % Reset Buttons
            app.ViewMaskButton.Enable = "off";
            app.ClearButton.Enable = 'off';

            % update
            t = string(datetime("now", "Format","HH:mm:ss"));
            msg = sprintf("%s >> Parameters reset", t);
            app.TextArea_4.Value = sprintf([repmat('%s\n', 1, numel(app.TextArea_2.Value)) '%s\n'], ...
            app.TextArea_4.Value{:}, msg);
            app.TextArea_5.Value = ">>Mask and Crop Status";
        end

        % Button down function: GraFTTab
        function GraFTTabButtonDown(app, event)
            % Update Parameters
            app = saveParams(app);

            % Disable Run Button if no data
            if ~isempty(app.G.Data.original)
                app.STARTButton.Enable = 'on';
            else
                app.STARTButton.Enable = 'off';
            end
        end

        % Button pushed function: CheckPARAMSButton
        function CheckPARAMSButtonPushed(app, event)
            % Get string
            str_out = preGraftParams(app.G.Parameters);
            % Show
            app.TextArea_6.Value = str_out;
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            % Run GraFT.m
            app = startGraFTing(app);
            
            % Initialize GraFTed results
            [app.G, app.Status] = resultsGraFTed(app.G, app.Status);

            % Plot
            if strcmp(app.Status.result_flag, "")
                cla(app.UIAxes4, "reset"); cla(app.UIAxes4_2, "reset");
                app = plotResults(app);
                colormap(app.UIAxes4, gray);
            else
                app.TextArea_7.Value =  app.Status.result_flag;
                return
            end
            
        end

        % Button down function: ResultsTab
        function ResultsTabButtonDown(app, event)
            % check if GraFT ran and has results
            if isempty(app.G.GraFTed.spatial)
                msg = 'GraFT results not detected. Run or load GRAFT to view results.';
                uialert(app.UIFigure, msg, 'Warning', 'Icon','warning', 'Modal',true); 
            end

        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            value = app.ColormapDropDown.Value;
            colormap(app.UIAxes4, value);
        end

        % Button pushed function: Button_4, Button_5
        function Button_5Pushed(app, event)
            switch event.Source.Text
                case '<'
                    if app.Status.result_idx > 1
                        app.Button_5.Enable = 'on';
                        app.Status.result_idx = app.Status.result_idx - 1;
                        if app.Status.result_idx == 1
                            app.Button_4.Enable = 'off';
                        end
                    end

                case '>'
                    if app.Status.result_idx < size(app.G.GraFTed.spatial,3)
                        app.Button_4.Enable = 'on';
                        app.Status.result_idx = app.Status.result_idx + 1;
                        if app.Status.result_idx == size(app.G.GraFTed.spatial,3)
                            app.Button_5.Enable = 'off';
                        end
                    end
            end
            
            % Plot
            plotResults(app);
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            selection = [app.OriginalCheckBox.Value, ...
                         app.GraFTDictionaryLearningCheckBox.Value, ...
                         app.AbsDifferenceCheckBox.Value];
            setSpatialUI(app, selection);
        end

        % Button down function: SpatialTab
        function SpatialTabButtonDown(app, event)
            % check if data exists to show images
            if isempty(app.G.Data.pre_graft)
                app.OriginalCheckBox.Enable = 'off';
                app.OriginalCheckBox.Value = 0;
            else
                app.OriginalCheckBox.Enable = 'on';
            end

            if isempty(app.G.GraFTed.spatial)
                app.GraFTDictionaryLearningCheckBox.Value = 0;
                app.GraFTDictionaryLearningCheckBox.Enable = 'off';
                app.AbsDifferenceCheckBox.Value = 0;
                app.AbsDifferenceCheckBox.Enable = 'off';
            else
                app.GraFTDictionaryLearningCheckBox.Enable = 'on';
                app.AbsDifferenceCheckBox.Enable = 'on';
            end

            if isempty(app.G.GraFTed.spatial) && isempty(app.G.Data.pre_graft)
                app.LoadButton_2.Enable = 'off';
                app.GridLayout80.Visible = 'off';
            else
                app.LoadButton_2.Enable = 'on';
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed2(app, event)
            switch app.PlayButton.Text
                case 'Play'
                    app.PlayButton.Text = 'Stop';
                    app.Status.m_stop = 0;
                    playMovie(app);
                case 'Stop'
                    app.PlayButton.Text = 'Play';
                    app.Status.m_stop = 1;
            end
        end

        % Value changed function: AbsDifferenceCheckBox, 
        % ...and 2 other components
        function OriginalCheckBoxValueChanged(app, event)
            app.LoadButton_2.Enable = 'on';
            app.PlayButton.Enable = 'off';
            app.fpsEditField.Enable = 'on';
        end

        % Button pushed function: DetectButton_3
        function DetectButton_3Pushed(app, event)
            if ~isempty(app.G.GraFTed.spatial) && ~isempty(app.G.GraFTed.temporal)
                app = setROIsUI(app, app.G);
                imagesc(app.G.GraFTed.projection_cols, 'Parent', app.UIAxes5, 'ButtonDownFcn',@app.mouse_click);
                axis(app.UIAxes5, 'image');
                set(app.UIAxes5, 'XTick', [], 'YTick', [])
                title(app.UIAxes5, 'Distinguishable Colors ROIs');
                app.UIAxes5.Title.Color = 'w';
            end
        end

        % Button pushed function: ResetDefaultButton
        function ResetDefaultButtonPushed(app, event)
            app.fset.appearance_color = 'DarkMode';
            app.ColorModeDropDown.Value = app.fset.appearance_color;
            app.fset.fontScale = 800;
            app.FontScaleEditField.Value = app.fset.fontScale;
            SaveButton_2Pushed(app)
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            app.fset.appearance_color = app.ColorModeDropDown.Value;
            app.fset.fontScale = app.FontScaleEditField.Value;
            % Update
            new_width = app.UIFigure.Position(3);
            newFontSize = double(new_width)*app.fset.fontSizes/app.fset.fontScale;
            set(app.fset.fontHandles, {'FontSize'}, num2cell(newFontSize))
        end

        % Double-clicked callback: ListBox_2
        function ListBox_2DoubleClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GraFTAppLabel = uilabel(app.GridLayout);
            app.GraFTAppLabel.Text = 'GraFT - App';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.NewLoadTab = uitab(app.TabGroup);
            app.NewLoadTab.Title = 'New Load';
            app.GridLayout2 = uigridlayout(app.NewLoadTab);
            app.LoadDataPanel = uipanel(app.GridLayout2);
            app.LoadDataPanel.Title = 'Load Data';
            app.GridLayout4 = uigridlayout(app.LoadDataPanel);
            app.Panel_5 = uipanel(app.GridLayout4);
            app.GridLayout10 = uigridlayout(app.Panel_5);
            app.TextArea = uitextarea(app.GridLayout10);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'>>'; ''};
            app.ListBox = uilistbox(app.GridLayout10);
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {'Item 1'};
            app.GridLayout11 = uigridlayout(app.GridLayout10);
            app.SelectButton = uibutton(app.GridLayout11, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.ALLButton = uibutton(app.GridLayout11, 'push');
            app.ALLButton.ButtonPushedFcn = createCallbackFcn(app, @ALLButtonPushed, true);
            app.ALLButton.Text = 'ALL';
            app.ShiftClickGroupSelectCtrlClickMultiSelectLabel = uilabel(app.GridLayout10);
            app.ShiftClickGroupSelectCtrlClickMultiSelectLabel.Text = {'Shift + Click = Group Select'; 'Ctrl + Click = Multi-Select'};
            app.SelectFileTypePanel = uipanel(app.GridLayout4);
            app.SelectFileTypePanel.Title = 'Select File Type';
            app.GridLayout6 = uigridlayout(app.SelectFileTypePanel);
            app.FolderButton = uibutton(app.GridLayout6, 'state');
            app.FolderButton.ValueChangedFcn = createCallbackFcn(app, @FolderButtonValueChanged, true);
            app.FolderButton.Text = 'Folder';
            app.FileButton = uibutton(app.GridLayout6, 'state');
            app.FileButton.ValueChangedFcn = createCallbackFcn(app, @FolderButtonValueChanged, true);
            app.FileButton.Text = 'File';
            app.S3Button = uibutton(app.GridLayout6, 'state');
            app.S3Button.ValueChangedFcn = createCallbackFcn(app, @FolderButtonValueChanged, true);
            app.S3Button.Enable = 'off';
            app.S3Button.Text = 'S3';
            app.LocalfolderLabel = uilabel(app.GridLayout6);
            app.LocalfolderLabel.Text = 'Local folder';
            app.IndividualfileLabel = uilabel(app.GridLayout6);
            app.IndividualfileLabel.Text = 'Individual file';
            app.GRAFTObjectLabel = uilabel(app.GridLayout6);
            app.GRAFTObjectLabel.Text = 'GRAFT Object';
            app.RemotedataLabel = uilabel(app.GridLayout6);
            app.RemotedataLabel.Text = 'Remote data';
            app.GRAFTButton = uibutton(app.GridLayout6, 'state');
            app.GRAFTButton.Enable = 'off';
            app.GRAFTButton.Text = 'GRAFT';
            app.Panel_2 = uipanel(app.GridLayout2);
            app.GridLayout67 = uigridlayout(app.Panel_2);
            app.GridLayout68 = uigridlayout(app.GridLayout67);
            app.Label = uilabel(app.GridLayout68);
            app.Label.Text = 'C:\Users\estra\Documents\MATLAB\GraFT App 623';
            app.PATHLabel = uilabel(app.GridLayout68);
            app.PATHLabel.Text = 'PATH:';
            app.GridLayout69 = uigridlayout(app.GridLayout67);
            app.WelcometoGraFTSelectfiletypebelowtobeginLabel = uilabel(app.GridLayout69);
            app.WelcometoGraFTSelectfiletypebelowtobeginLabel.Text = {'Welcome to GraFT! Select file type below to begin.'; 'Created by Alex Estrada'};
            app.NewLoadButton = uibutton(app.GridLayout69, 'push');
            app.NewLoadButton.ButtonPushedFcn = createCallbackFcn(app, @NewLoadButtonPushed, true);
            app.NewLoadButton.Enable = 'off';
            app.NewLoadButton.Text = 'New Load';
            app.PreprocessTab = uitab(app.TabGroup);
            app.PreprocessTab.Title = 'Preprocess';
            app.GridLayout7 = uigridlayout(app.PreprocessTab);
            app.GridLayout16 = uigridlayout(app.GridLayout7);
            app.UIAxes = uiaxes(app.GridLayout16);
            app.TabGroup3 = uitabgroup(app.GridLayout16);
            app.MotionCorrectionTab = uitab(app.TabGroup3);
            app.MotionCorrectionTab.Title = 'Motion Correction';
            app.GridLayout25 = uigridlayout(app.MotionCorrectionTab);
            app.Label_2 = uilabel(app.GridLayout25);
            app.Label_2.Text = 'Correcting for motion artifacts or drift in the imaging data to improve the accuracy of ROI analysis and event detection';
            app.GridLayout26 = uigridlayout(app.GridLayout25);
            app.Available1NoRMCorreNonRigitMotionCorrectionLabel = uilabel(app.GridLayout26);
            app.Available1NoRMCorreNonRigitMotionCorrectionLabel.Text = {'Available:'; ''; '1. NoRMCorre: Non-Rigit Motion Correction'};
            app.GridLayout27 = uigridlayout(app.GridLayout26);
            app.MethodDropDownLabel = uilabel(app.GridLayout27);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.GridLayout27);
            app.MethodDropDown.Items = {'NoRMCorre'};
            app.MethodDropDown.Value = 'NoRMCorre';
            app.MotionCorrectButton = uibutton(app.GridLayout27, 'push');
            app.MotionCorrectButton.ButtonPushedFcn = createCallbackFcn(app, @MotionCorrectButtonPushed, true);
            app.MotionCorrectButton.Text = 'Motion Correct';
            app.CropTab = uitab(app.TabGroup3);
            app.CropTab.Title = 'Crop';
            app.CropTab.ButtonDownFcn = createCallbackFcn(app, @CropTabButtonDown, true);
            app.GridLayout22 = uigridlayout(app.CropTab);
            app.InteractiveselectionofareasquareregiontocropLabel = uilabel(app.GridLayout22);
            app.InteractiveselectionofareasquareregiontocropLabel.Text = 'Interactive selection of area (square region) to crop';
            app.GridLayout28 = uigridlayout(app.GridLayout22);
            app.GridLayout29 = uigridlayout(app.GridLayout28);
            app.SelectAreaButton = uibutton(app.GridLayout29, 'push');
            app.SelectAreaButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAreaButtonPushed, true);
            app.SelectAreaButton.Text = 'Select Area';
            app.Label_3 = uilabel(app.GridLayout29);
            app.Label_3.Text = 'Note: Crop automatically performs an average projection for optimized cropping selection.';
            app.GridLayout30 = uigridlayout(app.GridLayout28);
            app.ApplyButton = uibutton(app.GridLayout30, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Enable = 'off';
            app.ApplyButton.Text = 'Apply';
            app.CancelButton = uibutton(app.GridLayout30, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.CancelButton.Enable = 'off';
            app.CancelButton.Text = 'Cancel';
            app.MaskTab = uitab(app.TabGroup3);
            app.MaskTab.Title = 'Mask';
            app.MaskTab.ButtonDownFcn = createCallbackFcn(app, @CropTabButtonDown, true);
            app.GridLayout22_2 = uigridlayout(app.MaskTab);
            app.GridLayout28_2 = uigridlayout(app.GridLayout22_2);
            app.GridLayout41 = uigridlayout(app.GridLayout28_2);
            app.FreehandSelectionsLabel = uilabel(app.GridLayout41);
            app.FreehandSelectionsLabel.Text = 'Free-hand Selection(s)';
            app.SelectAreaButton_2 = uibutton(app.GridLayout41, 'push');
            app.SelectAreaButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectAreaButton_2Pushed, true);
            app.SelectAreaButton_2.Text = 'Select Area';
            app.CreateButton = uibutton(app.GridLayout41, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAreaButton_2Pushed, true);
            app.CreateButton.Text = 'Create';
            app.LoadPARAMSmaskLabel = uilabel(app.GridLayout41);
            app.LoadPARAMSmaskLabel.Text = 'Load PARAMS mask';
            app.SelfLoadButton = uibutton(app.GridLayout41, 'push');
            app.SelfLoadButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAreaButton_2Pushed, true);
            app.SelfLoadButton.Text = 'Self-Load';
            app.PreComputedDropDownLabel = uilabel(app.GridLayout41);
            app.PreComputedDropDownLabel.Text = 'Pre-Computed';
            app.PreComputedDropDown = uidropdown(app.GridLayout41);
            app.PreComputedDropDown.Items = {'Mean', 'Mean + SD', 'Otsu', 'Triangle'};
            app.PreComputedDropDown.Value = 'Mean';
            app.GridLayout42 = uigridlayout(app.GridLayout28_2);
            app.Label_4 = uilabel(app.GridLayout42);
            app.Label_4.Text = {'Note: Mask automatically performs an average'; 'projection for optimized mask selection.'};
            app.GridLayout43 = uigridlayout(app.GridLayout42);
            app.GridLayout52 = uigridlayout(app.GridLayout43);
            app.ColorButton = uibutton(app.GridLayout43, 'push');
            app.ColorButton.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPushed, true);
            app.ColorButton.Text = 'Color';
            app.ApplyButton_2 = uibutton(app.GridLayout43, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_2Pushed, true);
            app.ApplyButton_2.Text = 'Apply';
            app.ClearButton = uibutton(app.GridLayout43, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_2Pushed, true);
            app.ClearButton.Text = 'Clear';
            app.ViewMaskButton = uibutton(app.GridLayout43, 'push');
            app.ViewMaskButton.ButtonPushedFcn = createCallbackFcn(app, @ViewMaskButtonPushed, true);
            app.ViewMaskButton.Text = 'View Mask';
            app.AddinsertselectprecomputedmaskoptionsLabel = uilabel(app.GridLayout22_2);
            app.AddinsertselectprecomputedmaskoptionsLabel.Text = 'Add / insert / select pre-computed mask options';
            app.WaveletDenoisingTab = uitab(app.TabGroup3);
            app.WaveletDenoisingTab.Title = 'Wavelet Denoising';
            app.WaveletDenoisingTab.ButtonDownFcn = createCallbackFcn(app, @CropTabButtonDown, true);
            app.GridLayout34 = uigridlayout(app.WaveletDenoisingTab);
            app.DenosingLabel = uilabel(app.GridLayout34);
            app.DenosingLabel.Text = ' Wavelet-based techniques to suppress noise while preserving relevant features';
            app.GridLayout26_2 = uigridlayout(app.GridLayout34);
            app.TextArea_3 = uitextarea(app.GridLayout26_2);
            app.TextArea_3.Value = {'Denoising Methods:'; ''; '1. BlockJS - Block James-Stein'; '2. Bayes    - Empirical Bayes'; '4. Minimax - Minimax Estimation'; '5. SURE     -Stein''s Unbiased Risk Estimate'; ''; 'Wavelet:'; ''; '1. sym    -Symlets'; '2. db        -Daubechies'; '3. bl        -Best-located Daubechies'; '4. haar    -Haar'; '5. beyl     -Beylkin'};
            app.GridLayout37 = uigridlayout(app.GridLayout26_2);
            app.DenoisingMethodDropDownLabel = uilabel(app.GridLayout37);
            app.DenoisingMethodDropDownLabel.Text = 'Denoising Method';
            app.DenoisingMethodDropDown = uidropdown(app.GridLayout37);
            app.DenoisingMethodDropDown.Items = {'BlockJS', 'Bayes', 'FDR', 'Minimax', 'SURE'};
            app.DenoisingMethodDropDown.Value = 'BlockJS';
            app.WaveletDropDownLabel = uilabel(app.GridLayout37);
            app.WaveletDropDownLabel.Text = 'Wavelet';
            app.WaveletDropDown = uidropdown(app.GridLayout37);
            app.WaveletDropDown.Items = {'sym', 'db', 'bl', 'haar', 'beyl'};
            app.WaveletDropDown.Value = 'sym';
            app.OrderEditFieldLabel = uilabel(app.GridLayout37);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.GridLayout37, 'numeric');
            app.OrderEditField.Limits = [2 45];
            app.OrderEditField.RoundFractionalValues = 'on';
            app.OrderEditField.Value = 2;
            app.LevelSpinnerLabel = uilabel(app.GridLayout37);
            app.LevelSpinnerLabel.Text = 'Level';
            app.LevelSpinner = uispinner(app.GridLayout37);
            app.LevelSpinner.Limits = [1 Inf];
            app.LevelSpinner.RoundFractionalValues = 'on';
            app.LevelSpinner.Value = 4;
            app.NoiseEstimateDropDownLabel = uilabel(app.GridLayout37);
            app.NoiseEstimateDropDownLabel.Text = 'Noise Estimate';
            app.NoiseEstimateDropDown = uidropdown(app.GridLayout37);
            app.NoiseEstimateDropDown.Items = {'Level Independent', 'Level Dependent'};
            app.NoiseEstimateDropDown.Value = 'Level Independent';
            app.ThresholdRuleDropDownLabel = uilabel(app.GridLayout37);
            app.ThresholdRuleDropDownLabel.Text = 'Threshold Rule';
            app.ThresholdRuleDropDown = uidropdown(app.GridLayout37);
            app.ThresholdRuleDropDown.Items = {'James-Stein'};
            app.ThresholdRuleDropDown.Value = 'James-Stein';
            app.GridLayout38 = uigridlayout(app.GridLayout26_2);
            app.DenoiseButton = uibutton(app.GridLayout38, 'push');
            app.DenoiseButton.Text = 'Denoise';
            app.AdvancedButton = uibutton(app.GridLayout38, 'state');
            app.AdvancedButton.ValueChangedFcn = createCallbackFcn(app, @AdvancedButtonValueChanged, true);
            app.AdvancedButton.Text = 'Advanced';
            app.SpatialFilteringTab = uitab(app.TabGroup3);
            app.SpatialFilteringTab.Title = 'Spatial Filtering';
            app.GridLayout36 = uigridlayout(app.SpatialFilteringTab);
            app.Label_5 = uilabel(app.GridLayout36);
            app.Label_5.Text = 'Applying spatial filters to highlight specific patterns or features';
            app.GridLayout54 = uigridlayout(app.GridLayout36);
            app.BaselineCorrectionTab = uitab(app.TabGroup3);
            app.BaselineCorrectionTab.Title = 'Baseline Correction';
            app.GridLayout44 = uigridlayout(app.BaselineCorrectionTab);
            app.Label_6 = uilabel(app.GridLayout44);
            app.Label_6.Text = 'Removing or adjusting the baseline fluorescence level to improve the accuracy of calcium event detection';
            app.ViewModePanel = uipanel(app.GridLayout7);
            app.ViewModePanel.Title = 'View Mode';
            app.GridLayout17 = uigridlayout(app.ViewModePanel);
            app.GridLayout19 = uigridlayout(app.GridLayout17);
            app.GridLayout20 = uigridlayout(app.GridLayout19);
            app.DetectButton = uibutton(app.GridLayout20, 'state');
            app.DetectButton.ValueChangedFcn = createCallbackFcn(app, @DetectButtonValueChanged, true);
            app.DetectButton.Text = 'Detect';
            app.NewLoadLabel = uilabel(app.GridLayout20);
            app.NewLoadLabel.Text = 'New Load';
            app.ResettoOriginalLabel = uilabel(app.GridLayout20);
            app.ResettoOriginalLabel.Enable = 'off';
            app.ResettoOriginalLabel.Text = 'Reset to Original';
            app.ResetButton_2 = uibutton(app.GridLayout20, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Enable = 'off';
            app.ResetButton_2.Text = 'Reset';
            app.GridLayout21 = uigridlayout(app.GridLayout19);
            app.IndividualimageviewLabel = uilabel(app.GridLayout21);
            app.IndividualimageviewLabel.Text = 'Individual image view';
            app.SingleViewButton = uibutton(app.GridLayout21, 'push');
            app.SingleViewButton.ButtonPushedFcn = createCallbackFcn(app, @SingleViewButtonPushed, true);
            app.SingleViewButton.Text = 'Single View';
            app.HistogramButton = uibutton(app.GridLayout21, 'push');
            app.HistogramButton.Enable = 'off';
            app.HistogramButton.Text = 'Histogram';
            app.ContrasttoolviewingonlyLabel = uilabel(app.GridLayout21);
            app.ContrasttoolviewingonlyLabel.Enable = 'off';
            app.ContrasttoolviewingonlyLabel.Text = {'Contrast tool '; '(viewing only)'};
            app.ProjectionButton = uibutton(app.GridLayout21, 'push');
            app.ProjectionButton.ButtonPushedFcn = createCallbackFcn(app, @SingleViewButtonPushed, true);
            app.ProjectionButton.Text = 'Projection';
            app.EnlargedmeanimagestackprojectionLabel = uilabel(app.GridLayout21);
            app.EnlargedmeanimagestackprojectionLabel.Text = {'Enlarged mean'; 'image stack projection'};
            app.GridLayout55 = uigridlayout(app.GridLayout19);
            app.TextArea_2 = uitextarea(app.GridLayout55);
            app.TextArea_2.Value = {'>>'};
            app.GridLayout56 = uigridlayout(app.GridLayout55);
            app.Button = uibutton(app.GridLayout56, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button.Text = '<';
            app.Button_2 = uibutton(app.GridLayout56, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '>';
            app.GridLayout53 = uigridlayout(app.GridLayout17);
            app.UIAxes2 = uiaxes(app.GridLayout53);
            zlabel(app.UIAxes2, 'Z')
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.GridLayout12 = uigridlayout(app.ParametersTab);
            app.OptionsPanel = uipanel(app.GridLayout12);
            app.OptionsPanel.Title = 'Options';
            app.GridLayout48 = uigridlayout(app.OptionsPanel);
            app.TextArea_4 = uitextarea(app.GridLayout48);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'>> PARAMETER LOG'};
            app.GridLayout49 = uigridlayout(app.GridLayout48);
            app.GridLayout50 = uigridlayout(app.GridLayout49);
            app.LoadaPARAMSfileLabel = uilabel(app.GridLayout50);
            app.LoadaPARAMSfileLabel.Text = 'Load a PARAMS file';
            app.LoadButton = uibutton(app.GridLayout50, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.GridLayout51 = uigridlayout(app.GridLayout49);
            app.SaveButton = uibutton(app.GridLayout51, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SavecurrentPARAMSLabel = uilabel(app.GridLayout51);
            app.SavecurrentPARAMSLabel.Text = {'Save current '; 'PARAMS'};
            app.NameEditFieldLabel = uilabel(app.GridLayout51);
            app.NameEditFieldLabel.Text = 'Name:';
            app.NameEditField = uieditfield(app.GridLayout51, 'text');
            app.GridLayout58 = uigridlayout(app.GridLayout49);
            app.ResetButton_3 = uibutton(app.GridLayout58, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Text = 'Reset';
            app.ResettodefaultvaluesLabel = uilabel(app.GridLayout58);
            app.ResettodefaultvaluesLabel.Text = 'Reset to default values';
            app.Panel_6 = uipanel(app.GridLayout12);
            app.GridLayout47 = uigridlayout(app.Panel_6);
            app.GridLayout60 = uigridlayout(app.GridLayout47);
            app.OptimizationPanel = uipanel(app.GridLayout60);
            app.OptimizationPanel.Title = 'Optimization';
            app.GridLayout57 = uigridlayout(app.OptimizationPanel);
            app.sparsityconstraintEditFieldLabel = uilabel(app.GridLayout57);
            app.sparsityconstraintEditFieldLabel.Text = 'sparsity constraint';
            app.sparsityconstraintEditField = uieditfield(app.GridLayout57, 'numeric');
            app.componentregularizationEditFieldLabel = uilabel(app.GridLayout57);
            app.componentregularizationEditFieldLabel.Text = 'component regularization';
            app.componentregularizationEditField = uieditfield(app.GridLayout57, 'numeric');
            app.temporalcorrelationregularizationEditFieldLabel = uilabel(app.GridLayout57);
            app.temporalcorrelationregularizationEditFieldLabel.Text = 'temporal correlation regularization';
            app.temporalcorrelationregularizationEditField = uieditfield(app.GridLayout57, 'numeric');
            app.AdvancedPanel = uipanel(app.GridLayout60);
            app.AdvancedPanel.Title = 'Advanced';
            app.GridLayout61 = uigridlayout(app.AdvancedPanel);
            app.tauEditFieldLabel = uilabel(app.GridLayout61);
            app.tauEditFieldLabel.Text = 'tau';
            app.tauEditField = uieditfield(app.GridLayout61, 'numeric');
            app.betaEditFieldLabel = uilabel(app.GridLayout61);
            app.betaEditFieldLabel.Text = 'beta';
            app.betaEditField = uieditfield(app.GridLayout61, 'numeric');
            app.numberofRWL1GFrepsEditFieldLabel = uilabel(app.GridLayout61);
            app.numberofRWL1GFrepsEditFieldLabel.Text = 'number of RWL1GF reps';
            app.numberofRWL1GFrepsEditField = uieditfield(app.GridLayout61, 'numeric');
            app.continuitystepsEditFieldLabel = uilabel(app.GridLayout61);
            app.continuitystepsEditFieldLabel.Text = 'continuity steps';
            app.continuitystepsEditField = uieditfield(app.GridLayout61, 'numeric');
            app.continuityregularizationEditFieldLabel = uilabel(app.GridLayout61);
            app.continuityregularizationEditFieldLabel.Text = 'continuity regularization';
            app.continuityregularizationEditField = uieditfield(app.GridLayout61, 'numeric');
            app.GridLayout63 = uigridlayout(app.GridLayout47);
            app.DataSizeAdjustmentsPanel = uipanel(app.GridLayout63);
            app.DataSizeAdjustmentsPanel.Title = 'Data Size Adjustments';
            app.GridLayout64 = uigridlayout(app.DataSizeAdjustmentsPanel);
            app.TextArea_5 = uitextarea(app.GridLayout64);
            app.TextArea_5.Value = {'>> Mask and Crop Status'};
            app.GridLayout65 = uigridlayout(app.GridLayout64);
            app.DetectButton_2 = uibutton(app.GridLayout65, 'push');
            app.DetectButton_2.ButtonPushedFcn = createCallbackFcn(app, @DetectButton_2Pushed, true);
            app.DetectButton_2.Text = 'Detect';
            app.UseCheckBox = uicheckbox(app.GridLayout65);
            app.UseCheckBox.Text = 'Use';
            app.UseCheckBox.Value = true;
            app.GraFTConstructionPanel = uipanel(app.GridLayout63);
            app.GraFTConstructionPanel.Title = 'GraFT Construction';
            app.GridLayout59 = uigridlayout(app.GraFTConstructionPanel);
            app.nonnegativespatialcoefficientsDropDown = uidropdown(app.GridLayout59);
            app.nonnegativespatialcoefficientsDropDown.Items = {'true', 'false'};
            app.nonnegativespatialcoefficientsDropDown.Value = 'true';
            app.nonnegativespatialcoefficientsDropDownLabel = uilabel(app.GridLayout59);
            app.nonnegativespatialcoefficientsDropDownLabel.Text = 'non-negative spatial coefficients';
            app.patchsizeEditFieldLabel = uilabel(app.GridLayout59);
            app.patchsizeEditFieldLabel.Text = 'patch size';
            app.usepatchGraFTDropDownLabel = uilabel(app.GridLayout59);
            app.usepatchGraFTDropDownLabel.Text = 'use patchGraFT';
            app.usepatchGraFTDropDown = uidropdown(app.GridLayout59);
            app.usepatchGraFTDropDown.Items = {'false', 'true'};
            app.usepatchGraFTDropDown.Value = 'false';
            app.patchsizeEditField = uieditfield(app.GridLayout59, 'numeric');
            app.numberofcomponentsEditFieldLabel = uilabel(app.GridLayout59);
            app.numberofcomponentsEditFieldLabel.Text = 'number of components';
            app.numberofcomponentsEditField = uieditfield(app.GridLayout59, 'numeric');
            app.nonnegativetemporalcomponentsDropDownLabel = uilabel(app.GridLayout59);
            app.nonnegativetemporalcomponentsDropDownLabel.Text = 'non-negative temporal components';
            app.nonnegativetemporalcomponentsDropDown = uidropdown(app.GridLayout59);
            app.nonnegativetemporalcomponentsDropDown.Items = {'true', 'false'};
            app.nonnegativetemporalcomponentsDropDown.Value = 'true';
            app.GraFTTab = uitab(app.TabGroup);
            app.GraFTTab.Title = 'GraFT!';
            app.GraFTTab.ButtonDownFcn = createCallbackFcn(app, @GraFTTabButtonDown, true);
            app.GridLayout8 = uigridlayout(app.GraFTTab);
            app.TabGroup2 = uitabgroup(app.GridLayout8);
            app.RunTab = uitab(app.TabGroup2);
            app.RunTab.Title = 'Run';
            app.GridLayout14 = uigridlayout(app.RunTab);
            app.Panel_7 = uipanel(app.GridLayout14);
            app.GridLayout71 = uigridlayout(app.Panel_7);
            app.GridLayout72 = uigridlayout(app.GridLayout71);
            app.CheckPARAMSButton = uibutton(app.GridLayout72, 'push');
            app.CheckPARAMSButton.ButtonPushedFcn = createCallbackFcn(app, @CheckPARAMSButtonPushed, true);
            app.CheckPARAMSButton.Text = 'Check PARAMS';
            app.ViewcurrentPARAMSloadedLabel = uilabel(app.GridLayout72);
            app.ViewcurrentPARAMSloadedLabel.Text = 'View current PARAMS loaded';
            app.RUNGRAFTLabel = uilabel(app.GridLayout71);
            app.RUNGRAFTLabel.Text = 'RUN GRAFT';
            app.GridLayout73 = uigridlayout(app.GridLayout71);
            app.STARTButton = uibutton(app.GridLayout73, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.GridLayout75 = uigridlayout(app.GridLayout71);
            app.StudyEditFieldLabel = uilabel(app.GridLayout75);
            app.StudyEditFieldLabel.Text = 'Study:';
            app.StudyEditField = uieditfield(app.GridLayout75, 'text');
            app.GridLayout76 = uigridlayout(app.GridLayout71);
            app.AuthorEditFieldLabel = uilabel(app.GridLayout76);
            app.AuthorEditFieldLabel.Text = 'Author';
            app.AuthorEditField = uieditfield(app.GridLayout76, 'text');
            app.TextArea_6 = uitextarea(app.GridLayout14);
            app.ResultsTab = uitab(app.TabGroup2);
            app.ResultsTab.Title = 'Results';
            app.ResultsTab.ButtonDownFcn = createCallbackFcn(app, @ResultsTabButtonDown, true);
            app.GridLayout84 = uigridlayout(app.ResultsTab);
            app.GridLayout85 = uigridlayout(app.GridLayout84);
            app.Panel_8 = uipanel(app.GridLayout85);
            app.Panel_9 = uipanel(app.Panel_8);
            app.UIAxes4_2 = uiaxes(app.Panel_9);
            app.UIAxes4_2.XTick = [];
            app.UIAxes4_2.YTick = [];
            app.UIAxes4_2.ZTick = [];
            app.GridLayout88 = uigridlayout(app.GridLayout85);
            app.ColormapDropDownLabel = uilabel(app.GridLayout88);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.GridLayout88);
            app.ColormapDropDown.Items = {'gray', 'parula', 'jet', 'sky'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'gray';
            app.GridLayout86 = uigridlayout(app.GridLayout84);
            app.UIAxes4 = uiaxes(app.GridLayout86);
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.UIAxes4.ZTick = [];
            app.TextArea_7 = uitextarea(app.GridLayout86);
            app.TextArea_7.Value = {'>>'};
            app.GridLayout87 = uigridlayout(app.GridLayout86);
            app.Button_4 = uibutton(app.GridLayout87, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_4.Text = '<';
            app.Button_5 = uibutton(app.GridLayout87, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '>';
            app.xxLabel = uilabel(app.GridLayout87);
            app.xxLabel.Text = 'x/x';
            app.AnalysisTab_2 = uitab(app.TabGroup);
            app.AnalysisTab_2.Title = 'Analysis';
            app.GridLayout83 = uigridlayout(app.AnalysisTab_2);
            app.TabGroup4 = uitabgroup(app.GridLayout83);
            app.ROIsTab = uitab(app.TabGroup4);
            app.ROIsTab.Title = 'ROIs';
            app.GridLayout89 = uigridlayout(app.ROIsTab);
            app.GridLayout90 = uigridlayout(app.GridLayout89);
            app.UIAxes5_2 = uiaxes(app.GridLayout90);
            app.UIAxes5_2.XTick = [];
            app.UIAxes5_2.XTickLabel = '';
            app.UIAxes5_2.YTick = [];
            app.UIAxes5_2.ZTick = [];
            app.UIAxes5 = uiaxes(app.GridLayout90);
            app.UIAxes5.XTick = [];
            app.UIAxes5.XTickLabel = '';
            app.UIAxes5.YTick = [];
            app.UIAxes5.ZTick = [];
            app.GridLayout91 = uigridlayout(app.GridLayout89);
            app.UIAxes5_4 = uiaxes(app.GridLayout91);
            app.UIAxes5_4.XTick = [];
            app.UIAxes5_4.XTickLabel = '';
            app.UIAxes5_4.YTick = [];
            app.UIAxes5_4.ZTick = [];
            app.GridLayout93 = uigridlayout(app.GridLayout91);
            app.TemporalViewModeDropDownLabel = uilabel(app.GridLayout93);
            app.TemporalViewModeDropDownLabel.Text = 'Temporal View Mode';
            app.TemporalViewModeDropDown = uidropdown(app.GridLayout93);
            app.TemporalViewModeDropDown.Items = {'Line', 'Image'};
            app.TemporalViewModeDropDown.Value = 'Line';
            app.GridLayout94 = uigridlayout(app.GridLayout91);
            app.UIAxes5_3 = uiaxes(app.GridLayout94);
            app.UIAxes5_3.XTick = [];
            app.UIAxes5_3.XTickLabel = '';
            app.UIAxes5_3.YTick = [];
            app.UIAxes5_3.ZTick = [];
            app.GridLayout96 = uigridlayout(app.GridLayout94);
            app.ListBox_2 = uilistbox(app.GridLayout96);
            app.ListBox_2.Items = {'ROI-1', 'ROI-2', 'ROI-3'};
            app.ListBox_2.DoubleClickedFcn = createCallbackFcn(app, @ListBox_2DoubleClicked, true);
            app.ListBox_2.Value = 'ROI-1';
            app.UITable = uitable(app.GridLayout96);
            app.UITable.ColumnName = {'Artifact List'};
            app.UITable.RowName = {'ROI'};
            app.UITable.Visible = 'off';
            app.GridLayout95 = uigridlayout(app.GridLayout91);
            app.DetectButton_3 = uibutton(app.GridLayout95, 'push');
            app.DetectButton_3.ButtonPushedFcn = createCallbackFcn(app, @DetectButton_3Pushed, true);
            app.DetectButton_3.Text = 'Detect';
            app.SpatialTab = uitab(app.TabGroup4);
            app.SpatialTab.Title = 'Spatial';
            app.SpatialTab.ButtonDownFcn = createCallbackFcn(app, @SpatialTabButtonDown, true);
            app.GridLayout15 = uigridlayout(app.SpatialTab);
            app.GridLayout79 = uigridlayout(app.GridLayout15);
            app.Slider = uislider(app.GridLayout79);
            app.PlayButton = uibutton(app.GridLayout79, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed2, true);
            app.PlayButton.Text = 'Play';
            app.GridLayout80 = uigridlayout(app.GridLayout15);
            app.ORIGINALPanel = uipanel(app.GridLayout80);
            app.ORIGINALPanel.Title = 'ORIGINAL';
            app.GridLayout81 = uigridlayout(app.ORIGINALPanel);
            app.UIAxes3 = uiaxes(app.GridLayout81);
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.GRAFTRECONSTRUCTEDPanel = uipanel(app.GridLayout80);
            app.GRAFTRECONSTRUCTEDPanel.Title = 'GRAFT RECONSTRUCTED';
            app.GridLayout81_2 = uigridlayout(app.GRAFTRECONSTRUCTEDPanel);
            app.UIAxes3_2 = uiaxes(app.GridLayout81_2);
            app.UIAxes3_2.XTick = [];
            app.UIAxes3_2.YTick = [];
            app.ABSDIFFERENCEPanel = uipanel(app.GridLayout80);
            app.ABSDIFFERENCEPanel.Title = 'ABS. DIFFERENCE';
            app.GridLayout81_3 = uigridlayout(app.ABSDIFFERENCEPanel);
            app.UIAxes3_3 = uiaxes(app.GridLayout81_3);
            app.UIAxes3_3.XTick = [];
            app.UIAxes3_3.YTick = [];
            app.GridLayout82 = uigridlayout(app.GridLayout15);
            app.LoadButton_2 = uibutton(app.GridLayout82, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.OriginalCheckBox = uicheckbox(app.GridLayout82);
            app.OriginalCheckBox.ValueChangedFcn = createCallbackFcn(app, @OriginalCheckBoxValueChanged, true);
            app.OriginalCheckBox.Text = 'Original';
            app.OriginalCheckBox.Value = true;
            app.GraFTDictionaryLearningCheckBox = uicheckbox(app.GridLayout82);
            app.GraFTDictionaryLearningCheckBox.ValueChangedFcn = createCallbackFcn(app, @OriginalCheckBoxValueChanged, true);
            app.GraFTDictionaryLearningCheckBox.Text = 'GraFT Dictionary Learning';
            app.AbsDifferenceCheckBox = uicheckbox(app.GridLayout82);
            app.AbsDifferenceCheckBox.ValueChangedFcn = createCallbackFcn(app, @OriginalCheckBoxValueChanged, true);
            app.AbsDifferenceCheckBox.Text = 'Abs. Difference';
            app.SelectPlotstoViewLabel = uilabel(app.GridLayout82);
            app.SelectPlotstoViewLabel.Text = 'Select Plots to View:';
            app.fpsEditFieldLabel = uilabel(app.GridLayout82);
            app.fpsEditFieldLabel.Text = 'fps';
            app.fpsEditField = uieditfield(app.GridLayout82, 'numeric');
            app.fpsEditField.Limits = [1 70];
            app.fpsEditField.Value = 1;
            app.frametostartEditFieldLabel = uilabel(app.GridLayout82);
            app.frametostartEditFieldLabel.Text = 'frame to start';
            app.frametostartEditField = uieditfield(app.GridLayout82, 'numeric');
            app.frametostartEditField.Limits = [1 100];
            app.frametostartEditField.RoundFractionalValues = 'on';
            app.frametostartEditField.Value = 1;
            app.SaveCurrentVideoButton = uibutton(app.GridLayout82, 'push');
            app.SaveCurrentVideoButton.Text = 'Save Current Video';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.GridLayout9 = uigridlayout(app.SettingsTab);
            app.AppearancePanel = uipanel(app.GridLayout9);
            app.AppearancePanel.Title = 'Appearance';
            app.GridLayout77 = uigridlayout(app.AppearancePanel);
            app.ColorModeDropDownLabel = uilabel(app.GridLayout77);
            app.ColorModeDropDownLabel.Text = 'Color Mode';
            app.ColorModeDropDown = uidropdown(app.GridLayout77);
            app.ColorModeDropDown.Items = {'DarkMode'};
            app.ColorModeDropDown.Value = 'DarkMode';
            app.FontScaleEditFieldLabel = uilabel(app.GridLayout77);
            app.FontScaleEditFieldLabel.Text = 'Font Scale';
            app.FontScaleEditField = uieditfield(app.GridLayout77, 'numeric');
            app.ParallelProcessingPanel = uipanel(app.GridLayout9);
            app.ParallelProcessingPanel.Title = 'Parallel Processing';
            app.OptionsPanel_2 = uipanel(app.GridLayout9);
            app.OptionsPanel_2.Title = 'Options';
            app.GridLayout78 = uigridlayout(app.GridLayout9);
            app.SaveButton_2 = uibutton(app.GridLayout78, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.ResetDefaultButton = uibutton(app.GridLayout78, 'push');
            app.ResetDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDefaultButtonPushed, true);
            app.ResetDefaultButton.Text = 'Reset Default';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.GridLayout92 = uigridlayout(app.AboutTab);
            app.Label2 = uilabel(app.GridLayout);
            app.Label2.Text = 'The details of GraFT are described in: A.S. Charles, N. Cermak, R. Affan, B. Scott, J. Schiller & G. Mishne. GraFT: Graph Filtered Temporal Dictionary Learning for Functional Neural Imaging.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraFT2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end