classdef GraFT_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Label2_3                       matlab.ui.control.Label
        Label2_4                       matlab.ui.control.Label
        GraFTanalysisLabel_2           matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        OpenDatasetTab                 matlab.ui.container.Tab
        SetUpPanel                     matlab.ui.container.Panel
        LoadDataButton                 matlab.ui.control.Button
        PathingSettingsLabel           matlab.ui.control.Label
        BrowseforFolderButton          matlab.ui.control.Button
        DATAFILESLabel                 matlab.ui.control.Label
        currentpathLabel               matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        Panel_3                        matlab.ui.container.Panel
        CurrentRangesLabel             matlab.ui.control.Label
        CropButton_3                   matlab.ui.control.Button
        Label_5                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        SpecifyCropRangesLabel         matlab.ui.control.Label
        XEditField_2                   matlab.ui.control.NumericEditField
        XEditField                     matlab.ui.control.NumericEditField
        YEditField_2                   matlab.ui.control.NumericEditField
        YEditField                     matlab.ui.control.NumericEditField
        ZEditField_2                   matlab.ui.control.NumericEditField
        ZEditField                     matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        YEditFieldLabel                matlab.ui.control.Label
        XEditFieldLabel                matlab.ui.control.Label
        Panel_2                        matlab.ui.container.Panel
        Button                         matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        Label_2                        matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        UIAxes                         matlab.ui.control.UIAxes
        ButtonGroup_2                  matlab.ui.container.ButtonGroup
        ProjectionButton               matlab.ui.control.ToggleButton
        CropButton_2                   matlab.ui.control.ToggleButton
        HistogramButton                matlab.ui.control.ToggleButton
        ViewButton                     matlab.ui.control.ToggleButton
        ThresholdLabel                 matlab.ui.control.Label
        ViewModeLabel                  matlab.ui.control.Label
        BackButton                     matlab.ui.control.Button
        ViewDropDown                   matlab.ui.control.DropDown
        OptionsPanel                   matlab.ui.container.Panel
        NoCropSelectedLabel            matlab.ui.control.Label
        ButtonGroup                    matlab.ui.container.ButtonGroup
        NoneButton                     matlab.ui.control.RadioButton
        NormcorreButton                matlab.ui.control.RadioButton
        AverageButton                  matlab.ui.control.RadioButton
        DropDown                       matlab.ui.control.DropDown
        DataDenoisingLabel             matlab.ui.control.Label
        ChooseButton                   matlab.ui.control.Button
        CroppingLabel                  matlab.ui.control.Label
        MotionCorrectionLabel          matlab.ui.control.Label
        DenoiseButton                  matlab.ui.control.Button
        DataLoadingPanel               matlab.ui.container.Panel
        MultiSelectCtrlClickLabel      matlab.ui.control.Label
        RangeShiftClickLabel           matlab.ui.control.Label
        LoadSelectionButton            matlab.ui.control.Button
        SelectAllButton                matlab.ui.control.Button
        ImagesListBox                  matlab.ui.control.ListBox
        AppStatusPanel                 matlab.ui.container.Panel
        StatusLamp_2                   matlab.ui.control.Lamp
        textLabel                      matlab.ui.control.Label
        PreprocessingTab               matlab.ui.container.Tab
        ComingSoonLabel                matlab.ui.control.Label
        GraFTTab                       matlab.ui.container.Tab
        Panel_4                        matlab.ui.container.Panel
        SavePanel                      matlab.ui.container.Panel
        Panel_5                        matlab.ui.container.Panel
        Label_6                        matlab.ui.control.Label
        FormatDropDown                 matlab.ui.control.DropDown
        FormatDropDownLabel            matlab.ui.control.Label
        Label2_7                       matlab.ui.control.Label
        SaveNameLabel                  matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        CurrentDirectoryLabel          matlab.ui.control.Label
        DataPropertiesLabel            matlab.ui.control.Label
        KeeporiginaldataFsimCheckBox   matlab.ui.control.CheckBox
        ChangeButton                   matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        AppStatusPanel_2               matlab.ui.container.Panel
        OutputhereLabel                matlab.ui.control.Label
        BackButton_2                   matlab.ui.control.Button
        MainParametersPanel            matlab.ui.container.Panel
        PlottingperpatchdisabledLabel  matlab.ui.control.Label
        ResultsButton                  matlab.ui.control.Button
        RunGraFTButton                 matlab.ui.control.Button
        LambdaEditField_2              matlab.ui.control.NumericEditField
        LamForbEditField_2             matlab.ui.control.NumericEditField
        LamCorrEditField_2             matlab.ui.control.NumericEditField
        NumberofComponentsEditField_2  matlab.ui.control.NumericEditField
        UsePatchCheckBox_2             matlab.ui.control.CheckBox
        PatchSizeEditField_2           matlab.ui.control.NumericEditField
        PlotCheckBox                   matlab.ui.control.CheckBox
        DefaultsButton                 matlab.ui.control.StateButton
        AdvancedButton                 matlab.ui.control.Button
        SetButton                      matlab.ui.control.Button
        SparsityLabel                  matlab.ui.control.Label
        ExtratimetraceweightLabel      matlab.ui.control.Label
        PreventoverslycorrelateddictionaryelementsLabel  matlab.ui.control.Label
        PerpatchNoteLabel              matlab.ui.control.Label
        LamForbEditField_2Label        matlab.ui.control.Label
        PatchSizeEditField_2Label      matlab.ui.control.Label
        NumberofComponentsEditField_2Label  matlab.ui.control.Label
        LamCorrEditField_2Label        matlab.ui.control.Label
        LambdaEditField_2Label         matlab.ui.control.Label
        Label2_5                       matlab.ui.control.Label
        Label2_6                       matlab.ui.control.Label
        SaveCurrentParametersLabel     matlab.ui.control.Label
        AdvancedOptionsPanel           matlab.ui.container.Panel
        NormalizeTemporalCheckBox      matlab.ui.control.CheckBox
        W_timeEditFieldLabel           matlab.ui.control.Label
        ContinuityTermEditField        matlab.ui.control.NumericEditField
        GradientTypeDropDown           matlab.ui.control.DropDown
        ContDecayEditField             matlab.ui.control.NumericEditField
        VerboseSpinner                 matlab.ui.control.Spinner
        NormalizeSpatialCheckBox       matlab.ui.control.CheckBox
        CreateMemmapCheckBox           matlab.ui.control.CheckBox
        ReduceDimCheckBox              matlab.ui.control.CheckBox
        LikelyFormDropDown             matlab.ui.control.DropDown
        LikelyFormDropDownLabel        matlab.ui.control.Label
        W_timeEditField                matlab.ui.control.NumericEditField
        CorrelationTypeDropDown        matlab.ui.control.DropDown
        TypeofdictionaryupdateLabel    matlab.ui.control.Label
        ControlhowmuchtoweighthepreviousestimateLabel  matlab.ui.control.Label
        CorrelationTypeDropDownLabel   matlab.ui.control.Label
        VerboseSpinnerLabel            matlab.ui.control.Label
        ContDecayEditFieldLabel        matlab.ui.control.Label
        GradientTypeDropDownLabel      matlab.ui.control.Label
        SpatialSmoothingKernelLabel    matlab.ui.control.Label
        InitializecorrelationkernelstructureLabel  matlab.ui.control.Label
        DecayrateofthecontinuationparameterLabel  matlab.ui.control.Label
        LevelofverboseoutputLabel      matlab.ui.control.Label
        ContinuityTermEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        Colors      % pallett
        Data        % data
        Status      % app status
        params      % params
        corr_kern   % corr_kern
    end
    
    methods (Access = private)
        
        function colors = get_colors(app)
            colors = cell(20,1);
            % PALLET
            colors{1} = '#000000';  % black
            colors{2} = '#FFFFFF';  % white
            colors{3} = '#303C4D';  % 
            colors{5} = '#0D1117';  % black background
            colors{4} = '#161B22';  % gray
            colors{6} = '#43A0EB';  % highlight blue
            colors{7} = '#238636';  % github green
            colors{8} = '#F8965A';  % lamp 'busy'
            app.Colors = colors;
        end
        
        function colorMePlz(app)
            get_colors(app);
            % UIFIGURE
            app.Label2_3.FontColor              = app.Colors{2};
            app.Label2_4.FontColor              = app.Colors{2};
            app.GraFTanalysisLabel_2.FontColor  = app.Colors{2};
            app.UIFigure.Color                  = app.Colors{4};
            % OPENDATASET tab
            app.OpenDatasetTab.BackgroundColor  = app.Colors{5};            % TAB
            app.OptionsPanel.BackgroundColor    = app.Colors{5};            % Panel
            app.OptionsPanel.ForegroundColor    = app.Colors{6};            % Foreground
            app.SetUpPanel.BackgroundColor      = app.Colors{5};            % Panel
            app.SetUpPanel.ForegroundColor      = app.Colors{6};            % Foreground
            app.AppStatusPanel.BackgroundColor  = app.Colors{5};            % Panel
            app.AppStatusPanel.ForegroundColor  = app.Colors{6};            % Foreground
            app.StatusLamp_2.Color                = app.Colors{7};          % Lamp
                % Data Loading Options
                app.DataLoadingPanel.BackgroundColor    = app.Colors{5};    % Panel
                app.DataLoadingPanel.ForegroundColor    = app.Colors{6};    % Foreground
                app.ImagesListBox.BackgroundColor       = app.Colors{4};    % List Box
                app.SelectAllButton.BackgroundColor     = app.Colors{4};    % Select All Button
                % Options Panel
                app.ButtonGroup.BackgroundColor     = app.Colors{5};        % Motion Correction
                app.ChooseButton.BackgroundColor    = app.Colors{4};        % Cropping
                    % Cropping
                    app.ButtonGroup_2.BackgroundColor       = app.Colors{5};
                    app.CropButton_2.BackgroundColor        = app.Colors{4};
                    app.HistogramButton.BackgroundColor     = app.Colors{4};
                    app.ProjectionButton.BackgroundColor    = app.Colors{4};
                    app.ViewButton.BackgroundColor      = app.Colors{4};
                app.DropDown.BackgroundColor            = app.Colors{4};    % Data Denoising
                app.Panel.BackgroundColor               = app.Colors{5};    % Cropping Tool
                app.Panel_2.BackgroundColor             = app.Colors{5};    % UIAxes
                app.Panel_3.BackgroundColor             = app.Colors{5};    % Crop Ranges
            % PreProcessing
            app.PreprocessingTab.BackgroundColor = app.Colors{5};
            % GraFT
            app.GraFTTab.BackgroundColor                = app.Colors{5};    % GraFT Tab
            app.MainParametersPanel.BackgroundColor     = app.Colors{5};    % Main GraFT parameters
            app.AdvancedOptionsPanel.BackgroundColor    = app.Colors{5};    % Advanced GraFT parameters
            app.Panel_4.BackgroundColor                 = app.Colors{5};
                app.AppStatusPanel_2.BackgroundColor    = app.Colors{5};    % 2nd app status
                app.SavePanel.BackgroundColor           = app.Colors{5};    % save panel
                    app.Panel_5.BackgroundColor         = app.Colors{4};
        end
        
        function setDefaults(app)
            % Data 
            app.Data.flag               = '';                               % no data loaded
            app.Data.Fsim               = [];                               % raw data
            app.Data.Fsim_motco         = [];                               % motion_corrected/denoised
            app.Data.S                  = [];                               % GraFT results
            app.Data.D                  = [];       
            % Open Dataset
            app.currentpathLabel.Text   = pwd;
            app.Status.MotionCorrection = 'None';
            app.Status.projection_mean  = 0;
            app.Status.projection_max   = 0;
            app.Status.im_num           = 0;
            app.Status.current_im       = 0;
            app.Status.denoise          = 0;
            % GraFT
            app.Status.adv_settings     = 0;
            % Visibilities
            app.Panel.Visible               = 'off';
            app.OptionsPanel.Visible        = 'off';
            app.DataLoadingPanel.Visible    = 'off';
            app.AppStatusPanel.Visible      = 'off';
        end

        function paramsDefault(app)
            % basic
            app.params.lambda           = 0.05;
            app.params.lamForb          = 0.2;
            app.params.lamCorr          = 0.1;
            app.params.n_dict           = 5;
            app.params.patchSize        = 50;
            app.params.usePatch         = true;
            app.params.plot             = true;
            % advanced
            app.params.lamCont          = 0.1;
            app.params.grad_type        = 'Full Optimization';
            app.params.lamContStp       = 0.9;
            app.params.create_memmap    = false;
            app.params.verbose          = 0;
            app.params.normalizeSpatial = true;
            app.params.likely_form      = 'gaussian';
            % correlation kernel
            app.corr_kern.w_time         = 0;
            app.corr_kern.reduce_dim     = true;
            app.corr_kern.corrType       = 'embedding';
        end

        function data = motion_correction(app, type)
            % check for double/repeat motion correction
            if strcmp(type, app.Status.MotionCorrection)
                data = app.Data.Fsim_motco;
                return
            end
            
            % uiprogressdlg
            fig = uifigure;
            fig.Visible = 'off'; fig.WindowStyle = 'modal'; fig.Position = [1800, 500, 400, 125];
            movegui(fig, "center"); fig.Visible = 'on';
            d = uiprogressdlg(fig,'Title','Motion Correction in Progresss',...
                'Cancelable','on', 'Indeterminate','on','CancelText','Early Stop');
            drawnow

            t = tic;
            switch type
                case 'Average'
                    d.Message = 'Method - Average';
                    data = app.Data.Fsim/median(app.Data.Fsim(:));
                    app.Status.MotionCorrection = 'Average';
                case 'Norm corre'
                    d.Message = 'Method - Norm corre';
                    options_rigid = NoRMCorreSetParms('d1',size(app.Data.Fsim,1),'d2',size(app.Data.Fsim,2), ...
                                    'bin_width',50,'max_shift',10,'us_fac',2,'iter',5,'init_batch',200);
                    [data,~,~,~,~] = normcorre(app.Data.Fsim,options_rigid);
                    app.Status.MotionCorrection = 'Norm corre';
                case 'None'
                    d.Message = 'Method - None';
                    data = app.Data.Fsim;
                    app.Status.MotionCorrection = 'None';
            end
            close(d); close(fig);

            % Update User Status
            app.textLabel.Text = sprintf('%s \nMotion correction--"%s" in %.3f seconds', ...
                                    app.textLabel.Text, app.Status.MotionCorrection, toc(t));

            % Update denoising
            app.Status.denoise = 0;
        end
        
        function get_projection(app, type)
            % do projections and save them if first time
            switch type
                case 'Mean'
                    if app.Status.projection_mean == 0
                        % update
                        app.Status.projection_mean = 1;
                        % uiprogressdlg
                        fig = uifigure;
                        fig.Visible = 'off'; fig.WindowStyle = 'modal'; fig.Position = [1800, 500, 400, 125];
                        movegui(fig, "center"); fig.Visible = 'on';
                        d = uiprogressdlg(fig,'Title','Projection in Process',...
                            'Cancelable','on', 'Indeterminate','on','CancelText','Early Stop');
                        drawnow
                        d.Message = 'Mean Intensity Projection';
                        % do
                        app.Data.projection.mean = mean(app.Data.Fsim_motco,3);
                        close(d); close(fig);
                    else
                        return
                    end
                case 'Max'
                    % max intensity projection
                    if app.Status.projection_max == 0
                        % update
                        app.Status.projection_mean = 1;
                        % uiprogressdlg
                        fig = uifigure;
                        fig.Visible = 'off'; fig.WindowStyle = 'modal'; fig.Position = [1800, 500, 400, 125];
                        movegui(fig, "center"); fig.Visible = 'on';
                        d = uiprogressdlg(fig,'Title','Projection in Process',...
                            'Cancelable','on', 'Indeterminate','on','CancelText','Early Stop');
                        drawnow
                        d.Message = 'Max Intensity Projection';
                        % do
                        app.Data.projection.max = max(app.Data.Fsim_motco, [], 3);
                        close(d); close(fig);
                    else
                        return
                    end
            end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure, 'center')
            app.UIFigure.Visible = 'on';
            
            app.colorMePlz()
            app.setDefaults()
            app.paramsDefault()
        end

        % Button pushed function: BrowseforFolderButton
        function BrowseforFolderButtonPushed(app, event)
            new_path = uigetdir("Select Folder to Save");
            if new_path ~= 0                                                % case of cancellation
                app.currentpathLabel.Text = new_path;
                cd(new_path);
            else
                warning('No new folder selected')
            end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            app.AppStatusPanel.Visible  = 'on';
            app.OptionsPanel.Visible    = 'off';
            app.StatusLamp_2.Color      = app.Colors{8};
            app.textLabel.Text          = 'Loading Data...';
            
            app.Data.name    = app.currentpathLabel.Text;
            app.Data.files  = dir(fullfile(app.Data.name, '*.tiff'));
            pause(1);
            if size(app.Data.files, 1) == 0
                app.textLabel.Text = sprintf('%s \nNo images found', app.textLabel.Text);
                app.ImagesListBox.Items         = {};                       % ListBox Empty
                app.LoadSelectionButton.Visible = 'off';
                app.SelectAllButton.Visible     = 'off';

                app.StatusLamp_2.Color          = app.Colors{7};            % Status = not running
            else
                num_files = length(app.Data.files);
                app.Data.Steps = num_files;
                app.textLabel.Text = sprintf('%s \n%d images found', app.textLabel.Text, num_files);
                pause(0.5);
                
                % Show loaded images
                file_name_cell = cell(1,num_files);
                for i = 1:num_files
                    file_name_cell{i} = app.Data.files(i).name;
                end
                app.ImagesListBox.Items         = file_name_cell;           % ListBox Update
                app.DataLoadingPanel.Visible    = 'on';
                app.LoadSelectionButton.Visible = 'on';
                app.SelectAllButton.Visible     = 'on';
                
                % Update Status
                app.StatusLamp_2.Color = app.Colors{7};
            end
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            app.ImagesListBox.Value = app.ImagesListBox.Items;              % Select All Items Possible
        end

        % Button pushed function: LoadSelectionButton
        function LoadSelectionButtonPushed(app, event)
            % Selection
            num_selected = length(app.ImagesListBox.Value);
            app.textLabel.Text = sprintf('%s \n%d images selected', app.textLabel.Text, num_selected);
            
            % Checks selection type
            if num_selected == 0
                app.textLabel.Text = sprintf('%s \nMake selection', app.textLabel.Text);
                return
            elseif num_selected ~= app.Data.Steps
                selection = cell2struct(app.ImagesListBox.Value, 'name', 1);
                app.Data.files = selection;
            end

            % Load data
            app.StatusLamp_2.Color = app.Colors{8}; pause(0.5); t = tic;
            app.Data = load_app_data(app.Data);
            
            % Update App
            app.textLabel.Text = sprintf('%s, \n%s', app.textLabel.Text, app.Data.flag);
            app.textLabel.Text = sprintf('%s \nTime taken: %.3f seconds', app.textLabel.Text, toc(t));
            app.StatusLamp_2.Color          = app.Colors{7};
            app.DataLoadingPanel.Visible    = 'off';
                
            % Initiaiting Motion Correct with 'None' method
            app.Data.Fsim_motco = app.Data.Fsim;

            % Show options panel if loading complete
            if strcmp(app.Data.flag, 'Complete') == 1; app.OptionsPanel.Visible = 'on';end
        end

        % Button pushed function: ChooseButton
        function ChooseButtonPushed(app, event)
            % Performs Selected Motion Correction
            app.Data.Fsim_motco = app.motion_correction(app.ButtonGroup.SelectedObject.Text);

            % Show first image
            cla(app.UIAxes, "reset")
            imagesc(app.Data.Fsim_motco(:,:,1), 'Parent', app.UIAxes);      % use this axis image
            colormap(app.UIAxes, gray);
            
            % Label and Slider Values
            app.Status.im_num       = size(app.Data.Fsim_motco, 3);         % total image number
            app.Status.current_im   = 1;                                    % initializing current image
            app.Label_2.Text        = sprintf('1/%d', app.Status.im_num);   % creating total image #
            app.Slider.Value        = 1;                                    % initializing slider value
            if app.Status.im_num == 1                                       % disabling when only one image
                app.Slider.Enable   = 'off';
                app.Button.Enable   = 'off';
                app.Button_2.Enable = 'off';
            else
                app.Slider.Enable   = 'on';
                app.Slider.Visible  = 'on';
                app.Slider.Limits   = [1,app.Status.im_num];                % setting limit range when im > 1
            end

            % Default Panel Control
            app.ButtonGroup_2.HighlightColor        = app.Colors{6};
            app.Panel.Visible                       = 'on';
            app.Panel_2.Visible                     = 'on';
            app.Panel_3.Visible                     = 'off';
            app.ButtonGroup_2.Visible               = 'on';
            app.ButtonGroup_2.SelectedObject.Text   = 'View';
            % Remove other options while selecting
            app.AppStatusPanel.Visible          = 'off';
            app.ButtonGroup.Visible             = 'off';
            app.MotionCorrectionLabel.Visible   = 'off';
            app.DataDenoisingLabel.Visible      = 'off';
            app.DropDown.Visible                = 'off';
            app.DenoiseButton.Visible           = 'off';
        end

        % Button pushed function: DenoiseButton
        function DenoiseButtonPushed(app, event)
            app.Data.Fsim_motco = app.motion_correction(app.ButtonGroup.SelectedObject.Text);
            
            if app.Status.denoise == 0
                % update
                app.Status.denoise = 1;
                % uiprogressdlg
                fig = uifigure;
                fig.Visible = 'off'; fig.WindowStyle = 'modal'; fig.Position = [1800, 500, 400, 125];
                movegui(fig, "center"); fig.Visible = 'on';
                d = uiprogressdlg(fig,'Title','Data Denoising in Proccess',...
                    'Cancelable','on', 'Indeterminate','on','CancelText','Early Stop');
                drawnow
                d.Message = 'Denoising data...';
                % do
                tic;
                app.Data.Fsim_motco = denoiseCIinSpace(app.Data.Fsim_motco);
                close(d); close(fig);

                app.textLabel.Text = sprintf('%s, \nDenoising Done in %f seconds', app.textLabel.Text, toc);
            else
                app.textLabel.Text = sprintf('%s, \nData already completed denoising.', app.textLabel.Text);
            end
            
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            app.Status.current_im   = round(event.Value);
            switch app.ButtonGroup_2.SelectedObject.Text
                case 'View'
                    % change picture with slider
                    app.Label_2.Text = sprintf('%d/%d', app.Status.current_im, app.Status.im_num);
                    imagesc(app.Data.Fsim_motco(:,:,app.Status.current_im), 'Parent', app.UIAxes);
                    colormap(app.UIAxes, gray);
                case 'Projection'
                    % disable with projection
                case 'Histogram'
                    % threshold control with slider
            end
        end

        % Button pushed function: Button, Button_2
        function Button_2Pushed(app, event)
            switch app.ButtonGroup_2.SelectedObject.Text
                case 'View'
                    if app.Status.current_im > 1 && event.Source.Text=='<'
                        app.Status.current_im   = app.Status.current_im - 1;
                        app.Slider.Value        = app.Status.current_im;
                    elseif app.Status.current_im < app.Status.im_num && event.Source.Text=='>'
                        app.Status.current_im   = app.Status.current_im + 1;
                        app.Slider.Value        = app.Status.current_im;
                    end

                    app.Label_2.Text = sprintf('%d/%d', app.Status.current_im, app.Status.im_num);
                    imagesc(app.Data.Fsim_motco(:,:,app.Status.current_im), 'Parent', app.UIAxes);
                    colormap(app.UIAxes, gray);
            end
            
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            if any(size(app.Data.Fsim) ~= size(app.Data.Fsim_motco))
                app.NoCropSelectedLabel.Visible = 'off';
            else
                app.NoCropSelectedLabel.Visible = 'on';
            end
            
            % Disable 
            app.Panel.Visible                   = 'off';
            app.AppStatusPanel.Visible          = 'on';
            app.ButtonGroup.Visible             = 'on';
            app.MotionCorrectionLabel.Visible   = 'on';
            app.DataDenoisingLabel.Visible      = 'on';
            app.DropDown.Visible                = 'on';
            app.DenoiseButton.Visible           = 'on';
        end

        % Callback function: ButtonGroup_2, ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            switch app.ButtonGroup_2.SelectedObject.Text
                case 'View'
                    cla(app.UIAxes, "reset")

                    app.ChooseButtonPushed('')
                    app.Panel_3.Visible         = 'off';
                    app.Panel_2.Visible         = 'on';
                    app.Button_2.Visible        = 'on';
                    app.Button.Visible          = 'on';
                    app.Label_2.Visible         = 'on';
                    app.Slider.Visible          = 'on';
                
                case 'Crop'
                    % getting max sizes
                    [x_max, y_max, z_max]   = size(app.Data.Fsim_motco);
                    app.XEditField.Value    = 1;
                    app.YEditField.Value    = 1;
                    app.ZEditField.Value    = 1;
                    app.XEditField_2.Value  = x_max;
                    app.YEditField_2.Value  = y_max;
                    app.ZEditField_2.Value  = z_max;
                    % settings limits
                    app.XEditField.Limits   = [1 x_max];
                    app.YEditField.Limits   = [1 y_max];
                    app.XEditField_2.Limits = [1 x_max];
                    app.YEditField_2.Limits = [1 y_max];
                    if z_max > 1
                        app.ZEditField.Limits   = [1 z_max];
                        app.ZEditField_2.Limits = [1 z_max];
                        app.ZEditField.Editable = 'on';
                        app.ZEditField_2.Enable = 'on';
                    else
                        app.ZEditField.Value = 1;
                        app.ZEditField.Editable = 'off';
                        app.ZEditField_2.Enable = 'off';
                    end

                    app.Panel_2.Visible     = 'off';
                    app.Panel_3.Visible     = 'on';
                    app.Button_2.Visible    = 'off';
                    app.Button.Visible      = 'off';
                    app.Label_2.Visible     = 'off';

                case 'Projection'
                    cla(app.UIAxes, "reset")

                    % do default projection (mean)
                    app.get_projection('Mean');
                    imagesc(app.Data.projection.mean, 'Parent', app.UIAxes);
                    colormap(app.UIAxes, gray);

                    app.Panel_3.Visible         = 'off';
                    app.Panel_2.Visible         = 'on';
                    app.ThresholdLabel.Visible  = 'off';
                    app.ViewDropDown.Visible    = 'off';
                    app.Slider.Visible          = 'off';
                    app.Button_2.Visible        = 'off';
                    app.Button.Visible          = 'off';
                    app.Label_2.Visible         = 'off';
               
                case 'Histogram'
                    cla(app.UIAxes, "reset")

                    % https://www.mathworks.com/matlabcentral/answers/500983-change-axis-colour-matlab
                    % https://www.mathworks.com/help/matlab/ref/matlab.graphics.axis.axes-properties.html

                    hist = histogram(app.Data.Fsim_motco(:,:,:), 200, 'Normalization', 'pdf', 'Parent',app.UIAxes);
                    mx = max(hist.Data(:));
                    mn = min(hist.Data(:));

                    hist.BinLimits = [mn, mx];

                    xline(mx, 'Color', 'r', 'Parent',app.UIAxes)
                    xline(5, 'Color', 'r', 'Parent',app.UIAxes)

                    set(app.UIAxes, 'XColor', [1 1 1], 'YColor', [1,1,1]);
                    
                    app.UIAxes.visible          = 'on';
                    app.Slider.Enable           = 'on';
                    app.ThresholdLabel.Visible  = 'off';
                    app.ViewDropDown.Visible    = 'off';
            end
        end

        % Button pushed function: CropButton_3
        function CropButton_3Pushed(app, event)
            % range selection
            Xsel = app.XEditField.Value:app.XEditField_2.Value;
            Ysel = app.YEditField.Value:app.YEditField_2.Value;
            Zsel = app.ZEditField.Value:app.ZEditField_2.Value;
            
            % uiprogressdlg
            fig = uifigure;
            fig.Visible = 'off'; fig.WindowStyle = 'modal'; fig.Position = [1800, 500, 400, 125];
            movegui(fig, "center"); fig.Visible = 'on';
            d = uiprogressdlg(fig,'Title','Cropping in Progresss',...
                'Cancelable','on', 'Indeterminate','on','CancelText','Early Stop');
            drawnow
            % cropping
            t = tic;
            app.Data.Fsim_motco = app.Data.Fsim_motco(Xsel, Ysel, Zsel);
            % end figure
            close(d); close(fig);
            
            % removing original data from memory

            % Update new selection ranges
%             app.CurrentRangesLabel.Text = sprintf('Current Ranges: [%f:%f], [%f:%f], [%f:%f]', ...
%                                            app.XEditField.Value, app.XEditField_2.Value, ...
%                                            app.YEditField.Value, app.YEditField_2.Value, ...
%                                            app.ZEditField.Value, app.ZEditField_2.Value);

            % Update User Status
            app.textLabel.Text = sprintf('%s \nCropping Complete in %.3f seconds', ...
                                    app.textLabel.Text, toc(t));
            
            % Update Crop status
            if any(size(app.Data.Fsim) ~= size(app.Data.Fsim_motco))
                app.NoCropSelectedLabel.Visible = 'off';
            else
                app.NoCropSelectedLabel.Visible = 'on';
            end

            app.Panel_2.Visible = 'on';
            app.Panel_3.Visible = 'off';
            app.Panel.Visible = 'off';
            % Add back other options
            app.AppStatusPanel.Visible          = 'on';
            app.ButtonGroup.Visible             = 'on';
            app.MotionCorrectionLabel.Visible   = 'on';
            app.DataDenoisingLabel.Visible      = 'on';
            app.DropDown.Visible                = 'on';
            app.DenoiseButton.Visible           = 'on';
        end

        % Value changed function: DefaultsButton
        function DefaultsButtonValueChanged(app, event)
            % setting them back to defaults
            app.RunGraFTButton.Enable = 'off';
            % basic
            app.LambdaEditField_2.Value             = app.params.lambda;
            app.LamForbEditField_2.Value            = app.params.lamForb;
            app.LamCorrEditField_2.Value            = app.params.lamCorr;
            app.NumberofComponentsEditField_2.Value = app.params.n_dict;
            app.UsePatchCheckBox_2.Value            = app.params.usePatch;
            app.PatchSizeEditField_2.Value          = app.params.patchSize;
            app.PlotCheckBox.Value                  = app.params.plot;
            % advanced
            app.ContinuityTermEditField.Value       = app.params.lamCont;
            app.GradientTypeDropDown.Value          = 'Full Optimization';
            app.ContDecayEditField.Value            = app.params.lamContStp;
            app.CreateMemmapCheckBox.Value          = app.params.create_memmap;
            app.VerboseSpinner.Value                = app.params.verbose;
            app.NormalizeSpatialCheckBox.Value      = app.params.normalizeSpatial;
            app.LikelyFormDropDown.Value            = app.params.likely_form;
            % correlation kernel
            app.W_timeEditField.Value               = app.corr_kern.w_time;
            app.ReduceDimCheckBox.Value             = app.corr_kern.reduce_dim;
            app.CorrelationTypeDropDown.Value       = app.corr_kern.corrType;
        end

        % Value changed function: ContDecayEditField, 
        % ...and 16 other components
        function LambdaEditField_2ValueChanged(app, event)
            app.RunGraFTButton.Enable = 'off';
%             if app.UsePatchCheckBox_2.Value == 1
%                 app.PlotCheckBox.Value = 0;
%                 app.PlotCheckBox.Enable = 'off';
%             else
%                 app.PlotCheckBox.Enable = 'on';
%             end
        end

        % Button pushed function: AdvancedButton
        function AdvancedButtonPushed(app, event)
            app.Status.adv_settings = app.Status.adv_settings + 1;
            if mod(app.Status.adv_settings, 2)==1
                app.AdvancedOptionsPanel.Visible = 'on';
            else
                app.AdvancedOptionsPanel.Visible = 'off';
            end
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            % saving parameters to what they are currently
            % basic
            app.params.lambda           = app.LambdaEditField_2.Value;
            app.params.lamForb          = app.LamForbEditField_2.Value;
            app.params.lamCorr          = app.LamCorrEditField_2.Value;
            app.params.n_dict           = app.NumberofComponentsEditField_2.Value;
            app.params.patchSize        = app.PatchSizeEditField_2.Value;
            app.params.usePatch         = app.UsePatchCheckBox_2.Value;
            app.params.plot             = app.PlotCheckBox.Value;
            % advanced
            app.params.lamCont          = app.ContinuityTermEditField.Value;
            if strcmp(app.GradientTypeDropDown.Value, 'Full Optimization');app.params.grad_type = 'full_ls_cor';else; app.params.grad_type = 'norm';end
            app.params.lamContStp       = app.ContDecayEditField.Value;
            app.params.create_memmap    = app.CreateMemmapCheckBox.Value;
            app.params.verbose          = app.VerboseSpinner.Value;
            app.params.normalizeSpatial = app.NormalizeSpatialCheckBox.Value;
            app.params.likely_form      = app.LikelyFormDropDown.Value;
            % correlation kernel
            app.corr_kern.w_time         = app.W_timeEditField.Value;
            app.corr_kern.reduce_dim     = app.ReduceDimCheckBox.Value;
            app.corr_kern.corrType       = app.CorrelationTypeDropDown.Value;
            
            % panel reset + graft button 
            app.Status.adv_settings = 0;
            app.AdvancedOptionsPanel.Visible = 'off';
            app.RunGraFTButton.Enable = 'on';
        end

        % Button pushed function: RunGraFTButton
        function RunGraFTButtonPushed(app, event)
            if strcmp(app.Data.flag, '')
                app.TabGroup.SelectedTab = app.OpenDatasetTab;
                return
            end
            
            % uiprogressdlg
            fig = uifigure;
            fig.Visible = 'off'; fig.WindowStyle = 'modal'; fig.Position = [1800, 500, 400, 125];
            movegui(fig, "center"); fig.Visible = 'on';
            d = uiprogressdlg(fig,'Title','GraFT in process',...
                'Cancelable','on', 'Indeterminate','on','CancelText','Early Stop');
            drawnow

            % Actually run GraFT
            tic
            if app.params.usePatch
                [app.Data.S, app.Data.D] = patchGraFT(app.Data.Fsim_motco, app.params.n_dict,[],app.corr_kern,app.params);      % Learn the dictionary using patch-based code
            else
                [app.Data.D, app.Data.S] = GraFT(app.Data.Fsim_motco, [], app.corr_kern, app.params);   % Learn the dictionary (no patching - will be much more memory intensive and slower)
            end
            % end figure
            close(d); close(fig);

            % update text status
            app.textLabel.Text          = sprintf('%s \nGraFT Analysis Done! Time elapsed: %f seconds', ...
                                            app.textLabel.Text, toc);
            app.OutputhereLabel.Text    = app.textLabel.Text;

            app.Label_6.Text            = sprintf(['>>Data \n\nStructure with fields:\n\n' ...
                '           name: %s\n' ...
                '             files: %s\n' ...
                '            Fsim: [%sx%sx%s double]\n' ...
                'Fsim_motco: [%sx%sx%s double]\n' ...
                '                 S: [%sx%sx%s double]\n' ...
                '                 D: [%sx%s double]\n'], ...
                app.Data.name, ...
                string(size(app.Data.files,1)), ...
                string(size(app.Data.Fsim)), ...
                string(size(app.Data.Fsim_motco)), ...
                string(size(app.Data.S)), ...
                string(size(app.Data.D)));
            
            % results panel
            app.Label_7.Text = pwd;
            if app.params.normalizeSpatial
                normType = 'spatial';
            else
                normType = 'temporal';
            end
            saveName = sprintf('nfRun_%d_%d_%d_%d_%s.mat', ...
                round(100*app.params.lambda), round(100*app.params.lamForb),...
                round(100*app.params.lamCorr), round(100*app.params.lamCont),...
                normType); % Set the save name to store results in
            app.Label2_7.Text = saveName;

            app.Panel_4.Visible         = 'on';
            app.ResultsButton.Visible   = 'on';
        end

        % Button pushed function: BackButton_2
        function BackButton_2Pushed(app, event)
            app.Panel_4.Visible    = 'off';
            app.RunGraFTButton.Enable   = 'off';
        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)
            new_path = uigetdir("Select Folder to Save");
            app.Label_7.Text = new_path;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveDir  = app.Label_7.Text;                                   % Set the path where the output should be saved to
            saveName = app.Label2_7.Text;

            % update text status
            app.textLabel.Text          = sprintf('%s \nSaving results to %s...', ...
                                            app.textLabel.Text, saveName);
            app.OutputhereLabel.Text    = app.textLabel.Text;

            if ~app.KeeporiginaldataFsimCheckBox.Value
                app.Data.Fsim = '';
            end

            % uiprogressdlg
            fig = uifigure;
            fig.Visible = 'off'; fig.WindowStyle = 'modal'; fig.Position = [1800, 500, 400, 125];
            movegui(fig, "center"); fig.Visible = 'on';
            d = uiprogressdlg(fig,'Title','GraFT in process',...
                'Cancelable','on', 'Indeterminate','on','CancelText','Early Stop');
            drawnow
            str_to_save = app.Data;
            save(fullfile(saveDir,saveName),'-struct','str_to_save', '-v7.3')  % Save the results
            % end figure
            close(d); close(fig);

            % update text status
            app.textLabel.Text          = sprintf('%s \nSave complete!', ...
                                            app.textLabel.Text);
            app.OutputhereLabel.Text    = app.textLabel.Text;
        end

        % Button pushed function: ResultsButton
        function ResultsButtonPushed(app, event)
            app.Panel_4.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OpenDatasetTab = uitab(app.TabGroup);
            app.OpenDatasetTab.Title = 'Open Dataset';
            app.AppStatusPanel = uipanel(app.OpenDatasetTab);
            app.AppStatusPanel.Title = 'App Status';
            app.AppStatusPanel.Visible = 'off';
            app.textLabel = uilabel(app.AppStatusPanel);
            app.textLabel.Text = {'-text-'; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.StatusLamp_2 = uilamp(app.AppStatusPanel);
            app.DataLoadingPanel = uipanel(app.OpenDatasetTab);
            app.DataLoadingPanel.Title = 'Data Loading';
            app.DataLoadingPanel.Visible = 'off';
            app.ImagesListBox = uilistbox(app.DataLoadingPanel);
            app.ImagesListBox.Multiselect = 'on';
            app.ImagesListBox.Value = {'Item 1'};
            app.SelectAllButton = uibutton(app.DataLoadingPanel, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Text = 'Select All';
            app.LoadSelectionButton = uibutton(app.DataLoadingPanel, 'push');
            app.LoadSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSelectionButtonPushed, true);
            app.LoadSelectionButton.Text = 'Load Selection';
            app.RangeShiftClickLabel = uilabel(app.DataLoadingPanel);
            app.RangeShiftClickLabel.Text = 'Range (Shift + Click)';
            app.MultiSelectCtrlClickLabel = uilabel(app.DataLoadingPanel);
            app.MultiSelectCtrlClickLabel.Text = 'Multi-Select (Ctrl + Click)';
            app.OptionsPanel = uipanel(app.OpenDatasetTab);
            app.OptionsPanel.Title = 'Options';
            app.OptionsPanel.Visible = 'off';
            app.DenoiseButton = uibutton(app.OptionsPanel, 'push');
            app.DenoiseButton.ButtonPushedFcn = createCallbackFcn(app, @DenoiseButtonPushed, true);
            app.DenoiseButton.Text = 'Denoise';
            app.MotionCorrectionLabel = uilabel(app.OptionsPanel);
            app.MotionCorrectionLabel.Text = 'Motion Correction';
            app.CroppingLabel = uilabel(app.OptionsPanel);
            app.CroppingLabel.Text = 'Cropping';
            app.ChooseButton = uibutton(app.OptionsPanel, 'push');
            app.ChooseButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseButtonPushed, true);
            app.ChooseButton.Text = 'Choose';
            app.DataDenoisingLabel = uilabel(app.OptionsPanel);
            app.DataDenoisingLabel.Text = 'Data Denoising';
            app.DropDown = uidropdown(app.OptionsPanel);
            app.DropDown.Items = {'Wavelet'};
            app.DropDown.Value = 'Wavelet';
            app.ButtonGroup = uibuttongroup(app.OptionsPanel);
            app.AverageButton = uiradiobutton(app.ButtonGroup);
            app.AverageButton.Text = 'Average';
            app.AverageButton.Value = true;
            app.NormcorreButton = uiradiobutton(app.ButtonGroup);
            app.NormcorreButton.Text = 'Norm corre';
            app.NoneButton = uiradiobutton(app.ButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoCropSelectedLabel = uilabel(app.OptionsPanel);
            app.NoCropSelectedLabel.Text = '--No Crop Selected--';
            app.Panel = uipanel(app.OpenDatasetTab);
            app.ViewDropDown = uidropdown(app.Panel);
            app.ViewDropDown.Items = {'Mean', '95th Percentile', 'Maximum'};
            app.ViewDropDown.Visible = 'off';
            app.ViewDropDown.Value = 'Mean';
            app.BackButton = uibutton(app.Panel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.ViewModeLabel = uilabel(app.Panel);
            app.ViewModeLabel.Text = 'View Mode';
            app.ThresholdLabel = uilabel(app.Panel);
            app.ThresholdLabel.Visible = 'off';
            app.ThresholdLabel.Text = 'Threshold';
            app.ButtonGroup_2 = uibuttongroup(app.Panel);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ButtonGroup_2.ButtonDownFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ViewButton = uitogglebutton(app.ButtonGroup_2);
            app.ViewButton.Text = 'View';
            app.ViewButton.Value = true;
            app.HistogramButton = uitogglebutton(app.ButtonGroup_2);
            app.HistogramButton.Visible = 'off';
            app.HistogramButton.Text = 'Histogram';
            app.CropButton_2 = uitogglebutton(app.ButtonGroup_2);
            app.CropButton_2.Text = 'Crop';
            app.ProjectionButton = uitogglebutton(app.ButtonGroup_2);
            app.ProjectionButton.Text = 'Projection';
            app.Panel_2 = uipanel(app.Panel);
            app.Panel_2.Visible = 'off';
            app.UIAxes = uiaxes(app.Panel_2);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Slider = uislider(app.Panel_2);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '1/800000';
            app.Button_2 = uibutton(app.Panel_2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '<';
            app.Button = uibutton(app.Panel_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button.Text = '>';
            app.Panel_3 = uipanel(app.Panel);
            app.XEditFieldLabel = uilabel(app.Panel_3);
            app.XEditFieldLabel.Text = 'X';
            app.YEditFieldLabel = uilabel(app.Panel_3);
            app.YEditFieldLabel.Text = 'Y';
            app.ZEditFieldLabel = uilabel(app.Panel_3);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.Panel_3, 'numeric');
            app.ZEditField.Limits = [1 Inf];
            app.ZEditField.Value = 1;
            app.ZEditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.ZEditField_2.Limits = [1 Inf];
            app.ZEditField_2.Value = 1;
            app.YEditField = uieditfield(app.Panel_3, 'numeric');
            app.YEditField.Limits = [1 Inf];
            app.YEditField.Value = 1;
            app.YEditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.YEditField_2.Limits = [1 Inf];
            app.YEditField_2.Value = 1;
            app.XEditField = uieditfield(app.Panel_3, 'numeric');
            app.XEditField.Limits = [1 Inf];
            app.XEditField.Value = 1;
            app.XEditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.XEditField_2.Limits = [1 Inf];
            app.XEditField_2.Value = 1;
            app.SpecifyCropRangesLabel = uilabel(app.Panel_3);
            app.SpecifyCropRangesLabel.Text = 'Specify Crop Ranges';
            app.Label_3 = uilabel(app.Panel_3);
            app.Label_3.Text = ':';
            app.Label_4 = uilabel(app.Panel_3);
            app.Label_4.Text = ':';
            app.Label_5 = uilabel(app.Panel_3);
            app.Label_5.Text = ':';
            app.CropButton_3 = uibutton(app.Panel_3, 'push');
            app.CropButton_3.ButtonPushedFcn = createCallbackFcn(app, @CropButton_3Pushed, true);
            app.CropButton_3.Text = 'Crop';
            app.CurrentRangesLabel = uilabel(app.Panel_3);
            app.CurrentRangesLabel.Visible = 'off';
            app.CurrentRangesLabel.Text = 'Current Ranges:';
            app.SetUpPanel = uipanel(app.OpenDatasetTab);
            app.SetUpPanel.Title = 'Set-Up';
            app.currentpathLabel = uilabel(app.SetUpPanel);
            app.currentpathLabel.Text = '-current path-';
            app.DATAFILESLabel = uilabel(app.SetUpPanel);
            app.DATAFILESLabel.Text = 'DATA FILES';
            app.BrowseforFolderButton = uibutton(app.SetUpPanel, 'push');
            app.BrowseforFolderButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseforFolderButtonPushed, true);
            app.BrowseforFolderButton.Text = 'Browse for Folder';
            app.PathingSettingsLabel = uilabel(app.SetUpPanel);
            app.PathingSettingsLabel.Text = 'Pathing Settings';
            app.LoadDataButton = uibutton(app.SetUpPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Preprocessing';
            app.ComingSoonLabel = uilabel(app.PreprocessingTab);
            app.ComingSoonLabel.Text = 'Coming Soon';
            app.GraFTTab = uitab(app.TabGroup);
            app.GraFTTab.Title = 'GraFT';
            app.AdvancedOptionsPanel = uipanel(app.GraFTTab);
            app.AdvancedOptionsPanel.Title = 'Advanced Options';
            app.AdvancedOptionsPanel.Visible = 'off';
            app.ContinuityTermEditFieldLabel = uilabel(app.AdvancedOptionsPanel);
            app.ContinuityTermEditFieldLabel.Text = 'Continuity Term';
            app.LevelofverboseoutputLabel = uilabel(app.AdvancedOptionsPanel);
            app.LevelofverboseoutputLabel.Text = 'Level of verbose output';
            app.DecayrateofthecontinuationparameterLabel = uilabel(app.AdvancedOptionsPanel);
            app.DecayrateofthecontinuationparameterLabel.Text = 'Decay rate of the continuation parameter';
            app.InitializecorrelationkernelstructureLabel = uilabel(app.AdvancedOptionsPanel);
            app.InitializecorrelationkernelstructureLabel.Text = 'Initialize correlation kernel structure';
            app.SpatialSmoothingKernelLabel = uilabel(app.AdvancedOptionsPanel);
            app.SpatialSmoothingKernelLabel.Text = 'Spatial Smoothing Kernel';
            app.GradientTypeDropDownLabel = uilabel(app.AdvancedOptionsPanel);
            app.GradientTypeDropDownLabel.Text = 'Gradient Type';
            app.ContDecayEditFieldLabel = uilabel(app.AdvancedOptionsPanel);
            app.ContDecayEditFieldLabel.Text = 'Cont. Decay';
            app.VerboseSpinnerLabel = uilabel(app.AdvancedOptionsPanel);
            app.VerboseSpinnerLabel.Text = 'Verbose';
            app.CorrelationTypeDropDownLabel = uilabel(app.AdvancedOptionsPanel);
            app.CorrelationTypeDropDownLabel.Text = 'Correlation Type';
            app.ControlhowmuchtoweighthepreviousestimateLabel = uilabel(app.AdvancedOptionsPanel);
            app.ControlhowmuchtoweighthepreviousestimateLabel.Text = 'Control how much to weigh the previous estimate';
            app.TypeofdictionaryupdateLabel = uilabel(app.AdvancedOptionsPanel);
            app.TypeofdictionaryupdateLabel.Text = 'Type of dictionary update';
            app.CorrelationTypeDropDown = uidropdown(app.AdvancedOptionsPanel);
            app.CorrelationTypeDropDown.Items = {'embedding', 'convolution'};
            app.CorrelationTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @LambdaEditField_2ValueChanged, true);
            app.CorrelationTypeDropDown.Value = 'embedding';
            app.W_timeEditField = uieditfield(app.AdvancedOptionsPanel, 'numeric');
            app.W_timeEditField.ValueChangedFcn = createCallbackFcn(app, @LambdaEditField_2ValueChanged, true);
            app.LikelyFormDropDownLabel = uilabel(app.AdvancedOptionsPanel);
            app.LikelyFormDropDownLabel.Enable = 'off';
            app.LikelyFormDropDownLabel.Text = 'Likely Form';
            app.LikelyFormDropDown = uidropdown(app.AdvancedOptionsPanel);
            app.LikelyFormDropDown.Items = {'gaussian', 'poisson'};
            app.LikelyFormDropDown.ValueChangedFcn = createCallbackFcn(app, @LambdaEditField_2ValueChanged, true);
            app.LikelyFormDropDown.Enable = 'off';
            app.LikelyFormDropDown.Value = 'gaussian';
            app.ReduceDimCheckBox = uicheckbox(app.AdvancedOptionsPanel);
            app.ReduceDimCheckBox.ValueChangedFcn = createCallbackFcn(app, @LambdaEditField_2ValueChanged, true);
            app.ReduceDimCheckBox.Enable = 'off';
            app.ReduceDimCheckBox.Text = 'Reduce Dim';
            app.ReduceDimCheckBox.Value = true;
            app.CreateMemmapCheckBox = uicheckbox(app.AdvancedOptionsPanel);
            app.CreateMemmapCheckBox.ValueChangedFcn = createCallbackFcn(app, @LambdaEditField_2ValueChanged, true);
            app.CreateMemmapCheckBox.Enable = 'off';
            app.CreateMemmapCheckBox.Text = 'Create Memmap';
            app.NormalizeSpatialCheckBox = uicheckbox(app.AdvancedOptionsPanel);
            app.NormalizeSpatialCheckBox.ValueChangedFcn = createCallbackFcn(app, @LambdaEditField_2ValueChanged, true);
            app.NormalizeSpatialCheckBox.Enable = 'off';
            app.NormalizeSpatialCheckBox.Text = 'Normalize Spatial';
            app.NormalizeSpatialCheckBox.Value = true;
            app.VerboseSpinner = uispinner(app.AdvancedOptionsPanel);
            app.VerboseSpinner.Limits = [0 10];
            app.VerboseSpinner.ValueChangedFcn = createCallbackFcn(app, @LambdaEditField_2ValueChanged, true);
            app.ContDecayEditField = uieditfield(app.AdvancedOptionsPanel, 'numeric');
            app.ContDecayEditField.ValueChangedFcn = createCallbackFcn(app, @LambdaEditField_2ValueChanged, true);
            app.ContDecayEditField.Value = 0.9;
            app.GradientTypeDropDown = uidropdown(app.AdvancedOptionsPanel);
            app.GradientTypeDropDown.Items = {'Full Optimization', 'Norm'};
            app.GradientTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @LambdaEditField_2ValueChanged, true);
            app.GradientTypeDropDown.Value = 'Full Optimization';
            app.ContinuityTermEditField = uieditfield(app.AdvancedOptionsPanel, 'numeric');
            app.ContinuityTermEditField.ValueChangedFcn = createCallbackFcn(app, @LambdaEditField_2ValueChanged, true);
            app.ContinuityTermEditField.Value = 0.1;
            app.W_timeEditFieldLabel = uilabel(app.AdvancedOptionsPanel);
            app.W_timeEditFieldLabel.Text = 'W_time';
            app.NormalizeTemporalCheckBox = uicheckbox(app.AdvancedOptionsPanel);
            app.NormalizeTemporalCheckBox.Enable = 'off';
            app.NormalizeTemporalCheckBox.Text = 'Normalize Temporal';
            app.MainParametersPanel = uipanel(app.GraFTTab);
            app.MainParametersPanel.Title = 'Main Parameters';
            app.SaveCurrentParametersLabel = uilabel(app.MainParametersPanel);
            app.SaveCurrentParametersLabel.Text = 'Save Current Parameters';
            app.Label2_6 = uilabel(app.MainParametersPanel);
            app.Label2_6.Text = 'Breaks up the image into squares with patchSize pixels on each side.';
            app.Label2_5 = uilabel(app.MainParametersPanel);
            app.Label2_5.Text = 'Note: the final number of coefficients may be less than this due to lack of data variance and merging of components.';
            app.LambdaEditField_2Label = uilabel(app.MainParametersPanel);
            app.LambdaEditField_2Label.Text = 'Lambda';
            app.LamCorrEditField_2Label = uilabel(app.MainParametersPanel);
            app.LamCorrEditField_2Label.Text = 'LamCorr';
            app.NumberofComponentsEditField_2Label = uilabel(app.MainParametersPanel);
            app.NumberofComponentsEditField_2Label.Text = 'Number of Components';
            app.PatchSizeEditField_2Label = uilabel(app.MainParametersPanel);
            app.PatchSizeEditField_2Label.Text = 'Patch Size';
            app.LamForbEditField_2Label = uilabel(app.MainParametersPanel);
            app.LamForbEditField_2Label.Text = 'LamForb';
            app.PerpatchNoteLabel = uilabel(app.MainParametersPanel);
            app.PerpatchNoteLabel.Text = 'Per patch';
            app.PreventoverslycorrelateddictionaryelementsLabel = uilabel(app.MainParametersPanel);
            app.PreventoverslycorrelateddictionaryelementsLabel.Text = 'Prevent oversly correlated dictionary elements';
            app.ExtratimetraceweightLabel = uilabel(app.MainParametersPanel);
            app.ExtratimetraceweightLabel.Text = 'Extra time-trace weight';
            app.SparsityLabel = uilabel(app.MainParametersPanel);
            app.SparsityLabel.Text = 'Sparsity';
            app.SetButton = uibutton(app.MainParametersPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.AdvancedButton = uibutton(app.MainParametersPanel, 'push');
            app.AdvancedButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedButtonPushed, true);
            app.AdvancedButton.Text = 'Advanced';
            app.DefaultsButton = uibutton(app.MainParametersPanel, 'state');
            app.DefaultsButton.ValueChangedFcn = createCallbackFcn(app, @DefaultsButtonValueChanged, true);
            app.DefaultsButton.Text = 'Defaults';
            app.PlotCheckBox = uicheckbox(app.MainParametersPanel);
            app.PlotCheckBox.ValueChangedFcn = createCallbackFcn(app, @LambdaEditField_2ValueChanged, true);
            app.PlotCheckBox.Text = 'Plot';
            app.PlotCheckBox.Value = true;
            app.PatchSizeEditField_2 = uieditfield(app.MainParametersPanel, 'numeric');
            app.PatchSizeEditField_2.ValueChangedFcn = createCallbackFcn(app, @LambdaEditField_2ValueChanged, true);
            app.PatchSizeEditField_2.Value = 50;
            app.UsePatchCheckBox_2 = uicheckbox(app.MainParametersPanel);
            app.UsePatchCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @LambdaEditField_2ValueChanged, true);
            app.UsePatchCheckBox_2.Text = 'Use Patch';
            app.UsePatchCheckBox_2.Value = true;
            app.NumberofComponentsEditField_2 = uieditfield(app.MainParametersPanel, 'numeric');
            app.NumberofComponentsEditField_2.ValueChangedFcn = createCallbackFcn(app, @LambdaEditField_2ValueChanged, true);
            app.NumberofComponentsEditField_2.Value = 5;
            app.LamCorrEditField_2 = uieditfield(app.MainParametersPanel, 'numeric');
            app.LamCorrEditField_2.ValueChangedFcn = createCallbackFcn(app, @LambdaEditField_2ValueChanged, true);
            app.LamCorrEditField_2.Value = 0.1;
            app.LamForbEditField_2 = uieditfield(app.MainParametersPanel, 'numeric');
            app.LamForbEditField_2.ValueChangedFcn = createCallbackFcn(app, @LambdaEditField_2ValueChanged, true);
            app.LamForbEditField_2.Value = 0.2;
            app.LambdaEditField_2 = uieditfield(app.MainParametersPanel, 'numeric');
            app.LambdaEditField_2.ValueChangedFcn = createCallbackFcn(app, @LambdaEditField_2ValueChanged, true);
            app.LambdaEditField_2.Value = 0.05;
            app.RunGraFTButton = uibutton(app.MainParametersPanel, 'push');
            app.RunGraFTButton.ButtonPushedFcn = createCallbackFcn(app, @RunGraFTButtonPushed, true);
            app.RunGraFTButton.Enable = 'off';
            app.RunGraFTButton.Text = 'Run GraFT';
            app.ResultsButton = uibutton(app.MainParametersPanel, 'push');
            app.ResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsButtonPushed, true);
            app.ResultsButton.Visible = 'off';
            app.ResultsButton.Text = 'Results';
            app.PlottingperpatchdisabledLabel = uilabel(app.MainParametersPanel);
            app.PlottingperpatchdisabledLabel.Text = '(Plotting per patch disabled)';
            app.Panel_4 = uipanel(app.GraFTTab);
            app.Panel_4.Visible = 'off';
            app.BackButton_2 = uibutton(app.Panel_4, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButton_2Pushed, true);
            app.BackButton_2.Text = 'Back';
            app.AppStatusPanel_2 = uipanel(app.Panel_4);
            app.AppStatusPanel_2.Title = 'App Status';
            app.OutputhereLabel = uilabel(app.AppStatusPanel_2);
            app.OutputhereLabel.Text = 'Output here';
            app.SavePanel = uipanel(app.Panel_4);
            app.SavePanel.Title = 'Save';
            app.SaveButton = uibutton(app.SavePanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ChangeButton = uibutton(app.SavePanel, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Text = 'Change';
            app.KeeporiginaldataFsimCheckBox = uicheckbox(app.SavePanel);
            app.KeeporiginaldataFsimCheckBox.Text = 'Keep original data (Fsim)';
            app.DataPropertiesLabel = uilabel(app.SavePanel);
            app.DataPropertiesLabel.Text = 'Data Properties:';
            app.CurrentDirectoryLabel = uilabel(app.SavePanel);
            app.CurrentDirectoryLabel.Text = 'Current Directory:';
            app.Label_7 = uilabel(app.SavePanel);
            app.SaveNameLabel = uilabel(app.SavePanel);
            app.SaveNameLabel.Text = 'Save Name:';
            app.Label2_7 = uilabel(app.SavePanel);
            app.Label2_7.Text = 'Label2';
            app.FormatDropDownLabel = uilabel(app.SavePanel);
            app.FormatDropDownLabel.Text = 'Format';
            app.FormatDropDown = uidropdown(app.SavePanel);
            app.FormatDropDown.Items = {'.mat'};
            app.FormatDropDown.Value = '.mat';
            app.Panel_5 = uipanel(app.SavePanel);
            app.Label_6 = uilabel(app.Panel_5);
            app.Label_6.Text = {'Label'; '-'; '-'; '-'; '-'; '-'};
            app.GraFTanalysisLabel_2 = uilabel(app.UIFigure);
            app.GraFTanalysisLabel_2.Text = 'GraFT-analysis';
            app.Label2_4 = uilabel(app.UIFigure);
            app.Label2_4.Text = {'GraFT is a signal extraction method for spatio-temporal data. It uses a diffusion map to learn a graph over spatial pixels that enables for stochastic '; 'filtering of learned sparse representations over each pixel''s time-trace. The sparse representaions are modeled as in a heirarchical dictionary learning '; 'framework with correlated decompositions over the graph.'};
            app.Label2_3 = uilabel(app.UIFigure);
            app.Label2_3.Text = {'The details of GraFT are described in:'; 'A.S. Charles, N. Cermak, R. Affan, B. Scott, J. Schiller & G. Mishne. GraFT: Graph Filtered Temporal Dictionary Learning for Functional Neural Imaging.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraFT_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end