classdef GraFT2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        Label2                         matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        NewLoadTab                     matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        Panel_2                        matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        Panel_4                        matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        WelcometoGraFTSelectfiletypebelowtobeginLabel  matlab.ui.control.Label
        NewLoadButton                  matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        PATHLabel                      matlab.ui.control.Label
        LoadDataPanel                  matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        SelectFileTypePanel            matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        GRAFTButton                    matlab.ui.control.StateButton
        RemotedataLabel                matlab.ui.control.Label
        GRAFTObjectLabel               matlab.ui.control.Label
        IndividualfileLabel            matlab.ui.control.Label
        LocalfolderLabel               matlab.ui.control.Label
        S3Button                       matlab.ui.control.StateButton
        FileButton                     matlab.ui.control.StateButton
        FolderButton                   matlab.ui.control.StateButton
        Panel_5                        matlab.ui.container.Panel
        GridLayout10                   matlab.ui.container.GridLayout
        ShiftClickGroupSelectCtrlClickMultiSelectLabel  matlab.ui.control.Label
        GridLayout11                   matlab.ui.container.GridLayout
        ALLButton                      matlab.ui.control.Button
        SelectButton                   matlab.ui.control.Button
        ListBox                        matlab.ui.control.ListBox
        TextArea                       matlab.ui.control.TextArea
        PreprocessTab                  matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        ViewModePanel                  matlab.ui.container.Panel
        GridLayout17                   matlab.ui.container.GridLayout
        GridLayout19                   matlab.ui.container.GridLayout
        GridLayout21                   matlab.ui.container.GridLayout
        ContrasttoolviewingonlyLabel   matlab.ui.control.Label
        HistogramButton                matlab.ui.control.Button
        AddinsertselectprecomputedmaskoptionsLabel  matlab.ui.control.Label
        InteractiveselectionofareasquareregiontocropLabel  matlab.ui.control.Label
        MaskButton                     matlab.ui.control.Button
        CropButton                     matlab.ui.control.Button
        SingleViewButton               matlab.ui.control.Button
        IndividualimageviewLabel       matlab.ui.control.Label
        GridLayout20                   matlab.ui.container.GridLayout
        NewLoadLabel                   matlab.ui.control.Label
        DetectButton                   matlab.ui.control.StateButton
        MeanImageStackProjectionPanel  matlab.ui.container.Panel
        GridLayout18                   matlab.ui.container.GridLayout
        UIAxes_2                       matlab.ui.control.UIAxes
        GridLayout16                   matlab.ui.container.GridLayout
        UIAxes                         matlab.ui.control.UIAxes
        ParametersTab                  matlab.ui.container.Tab
        GridLayout12                   matlab.ui.container.GridLayout
        AdvancedPanel                  matlab.ui.container.Panel
        StandardPanel                  matlab.ui.container.Panel
        PreLoadPanel                   matlab.ui.container.Panel
        GraFTTab                       matlab.ui.container.Tab
        GridLayout8                    matlab.ui.container.GridLayout
        TabGroup2                      matlab.ui.container.TabGroup
        RunTab                         matlab.ui.container.Tab
        GridLayout14                   matlab.ui.container.GridLayout
        ResultsTab                     matlab.ui.container.Tab
        GridLayout15                   matlab.ui.container.GridLayout
        AnalysisTab_2                  matlab.ui.container.Tab
        SettingsTab                    matlab.ui.container.Tab
        GridLayout9                    matlab.ui.container.GridLayout
        GraFTAnalysisLabel             matlab.ui.control.Label
    end

    
    properties (Access = public)
        fset        % app.uifigure settings
        lDir        % launch directory
        G           % GRAFT
    end
    
    methods (Access = private)
        
        function setUIUX(app)
            % background colors
            app = colorMe(app, 'DarkMode');

            % default visibilities
            app = setVis(app);
            
            % font settings
            app.fset.fontHandles = findobj(app.UIFigure, '-property', 'FontSize');
            app.fset.fontSizes = cell2mat(get(app.fset.fontHandles, 'FontSize'));
        end
        
        function setDefault(app)
            % Dir
            app.lDir = pwd;
            app.Label.Text = app.lDir;

            % GRAFT
            app.G = GRAFT();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % hide app figure
            app.UIFigure.Visible = 'off';
            % UI/UX
            app.setUIUX()
            start_width = app.UIFigure.Position(3);
            newFontSize = double(start_width)*app.fset.fontSizes/800.0;
            set(app.fset.fontHandles, {'FontSize'}, num2cell(newFontSize))
            % Default Load
            app.setDefault()
            % show app figure
            app.UIFigure.Visible = 'on';
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            % auto updates font sizes
            try
                new_width = app.UIFigure.Position(3);
                newFontSize = double(new_width)*app.fset.fontSizes/800.0;
                set(app.fset.fontHandles, {'FontSize'}, num2cell(newFontSize))
            catch 
                % first time run?
                app.fset.userResolution = get(0, 'ScreenPixelsPerInch');
                app.fset.userScrnSize   = get(0, 'ScreenSize');
            end
        end

        % Value changed function: FileButton, FolderButton, S3Button
        function FolderButtonValueChanged(app, event)
            % Search for either folder or individual file. Function is used
            % for all buttons in New Load Tab.
            
            % Double pressed = New Load
            try
                if event.Value
                    app.NewLoadButtonPushed()
                    return
                end
            catch
                % this is if called by other event w/o .Value property [mat
                % struct loading]
            end
            
            % Check first time or cancel load
            if isequal(app.G.Encoding.data_path(1), 0)

                % Folder/File
                switch event.Source.Text
                    case 'Folder'
                        % Visibilities/Assignments
                        app.FileButton.Visible = 'off';
                        app.S3Button.Visible = 'off';
                        app.GRAFTButton.Visible = 'off';
                        app.G.Encoding.loading_type = 'Folder';
                        app.ListBox.Multiselect = 'on';
                        app.ALLButton.Enable = 'on';
                        % Get dir
                        path = uigetdir("Select Folder to Save");
                    case 'File'
                        % Visibilities/Assignments
                        app.FolderButton.Visible = 'off';
                        app.S3Button.Visible = 'off';
                        app.GRAFTButton.Visible = 'off';
                        app.G.Encoding.loading_type = 'File';
                        app.ListBox.Multiselect = 'off';
                        app.ALLButton.Enable = 'off';       % turn on if .tif
                        % Get file
                        [filename, path] = uigetfile({'*.h5;*.mat;*.tif'}, "Select File");
                        path = [path, filename];
                    case 'S3'
                        % TODO %
                        % Visibilities
                        app.FolderButton.Visible = 'off';
                        app.FileButton.Visible = 'off';
                        app.G.Encoding.loading_type = 'S3';
                end
    
                % Back Focus
                figure(app.UIFigure);
    
                % Encoding Update
                app.G.Encoding.data_path = path;
            end

            % Check if exists
            if ~isequal(app.G.Encoding.data_path(1), 0)
                % Extension
                [~, ~, extension] = fileparts(app.G.Encoding.data_path);
                % Update Text
                txt = sprintf(">>%s Selected\n", app.G.Encoding.loading_type);
                app.TextArea.Value = txt;
                % Search
                d = uiprogressdlg(app.UIFigure,'Title','Loading Selected',...
                    'Cancelable','off', 'Indeterminate','on','CancelText','Early Stop');
                d.Message = sprintf('File type - %s', extension);
                app.G = load_app_data2(app.G);
                close(d);
                % Check Flag
                if strcmp(app.G.Encoding.flag, "")
                    app.ALLButton.Enable = 'on';
                    app.SelectButton.Enable = 'on';
                    % Populate ListBox
                    app.ListBox.Items = app.G.Encoding.files.Name;
                else
                    app.TextArea.Value = sprintf("%s\n%s",app.TextArea.Value{:}, ...
                                                        app.G.Encoding.flag);
                end
                % Check ListBox Behaviour
                if strcmp(app.G.Encoding.loading_type, "File")
                    app.ListBox.Multiselect = 'off';
                    app.ALLButton.Enable = 'off';
                    if strcmp(extension, ".tif")
                        app.ALLButton.Enable = 'on';
                        app.ListBox.Multiselect = 'on';
                    end
                end
                % Enable new load
                app.NewLoadButton.Enable = 'on';
            else
                % if cancelled
                app.G.Encoding.loading_type = '';
                app.FolderButton.Visible = 'on';
                app.FileButton.Visible = 'on';
                app.S3Button.Visible = 'on';
                app.FolderButton.Value = 1;
                app.FileButton.Value = 1;
                app.S3Button.Value = 1;
                app.ALLButton.Enable = 'off';
                return
            end

        end

        % Button pushed function: NewLoadButton
        function NewLoadButtonPushed(app, event)
            % default visibilities
            app = setVis(app);
            % Default Load
            app.setDefault()
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            switch app.G.Encoding.loading_type
                case 'Folder'
                    if length(value)>1  % Multiselect
                        % Find rows with matching names
                        matchingRows = ismember(app.G.Encoding.files.Name, value);
                        
                        % Create a new table with matching rows
                        newTable = app.G.Encoding.files(matchingRows, :);
                        
                        % Check for uniformity in columns (excluding the 'Name' column)
                        uniformityFlag = true; % Assume uniformity initially
                        numColumns = size(newTable, 2) - 1; % Number of columns excluding 'Name'
                        nonUniformColumns = cell(1, numColumns); % Preallocate cell array
                        
                        % Iterate over each column (excluding the 'Name' column)
                        index = 1; % Index for nonUniformColumns
                        for colIndex = 1:size(newTable, 2)
                            if ~strcmp(newTable.Properties.VariableNames{colIndex}, 'Name') % Skip the 'Name' column
                                uniqueValues = unique(newTable.(colIndex));
                                if numel(uniqueValues) > 1
                                    uniformityFlag = false; % Set flag to indicate non-uniformity
                                    nonUniformColumns{index} = newTable.Properties.VariableNames{colIndex};
                                    index = index + 1;
                                end
                            end
                        end
                        
                        % Trim unused cells in nonUniformColumns
                        nonUniformColumns = nonUniformColumns(1:index-1);
                        
                        % Display warning if the selected rows are not uniform
                        if ~uniformityFlag
                            warningMsg = 'Warning: The selected rows are not uniform in the following column(s): ';
                            for i = 1:numel(nonUniformColumns)
                                warningMsg = [warningMsg, nonUniformColumns{i}, ', '];
                            end
                            warningMsg = warningMsg(1:end-2); % Remove the last comma and space
                            app.TextArea.Value = sprintf("%s",warningMsg);
                            % Disable Selection
                            app.SelectButton.Enable = 'off';
                        else
                            % Enable Selection
                            app.SelectButton.Enable = 'on';
                        end
                        
                    else % Single Select
                        row = app.G.Encoding.files(strcmp(app.G.Encoding.files.Name, value), :);
                        txt = sprintf("          name: %s\n" + ...
                            "                    format: %s\n" + ...
                            "                     bytes: %d\n " + ...
                            "                      size: [%dx%d]\n" + ...
                            "               colortype: %s\n", ...
                                row.Name{1}, row.Format{1}, row.FileSize(1), ...
                                row.Height(1), row.Width(1), row.ColorType{1});
                        app.TextArea.Value = sprintf('>>Folder Selected\n>>current_file\n           %s', txt);
                        % Enable Selection
                        app.SelectButton.Enable = 'on';
                    end

                case 'File'
                    % Extension
                    [~, ~, extension] = fileparts(app.G.Encoding.data_path);

                    % Case of 'select all' .tif file
                    if height(app.G.Encoding.files)>1 && strcmp(extension, '.tif')
                        app.TextArea.Value = sprintf(">>File Selected\n>>ALL");
                        return
                    end

                    % Find file
                    row = app.G.Encoding.files(strcmp(app.G.Encoding.files.Name, value), :);
                    switch extension
                        case '.tif'
                            % Update
                            txt = sprintf("          name: %s\n" + ...
                                    "                   filesize: %s\n" + ...
                                    "                       size: [%dx%d]\n" + ...
                                    "               colortype: %s\n", ...
                                        row.Name, row.FileSize, ...
                                        row.Height, row.Width, ...
                                        row.ColorType{:});
                            app.TextArea.Value = sprintf(['>>File Selected\n>>info\n' ...
                                                          '           %s'], txt);
                        case '.mat'
                            % Update
                            txt = sprintf("          name: %s\n" + ...
                                    "                       size: [%s]\n" + ...
                                    "                    class: %s\n", ...
                                        row.Name{:}, row.Size{:}, row.Class{:});
                            app.TextArea.Value = sprintf(['>>File Selected\n>>info\n' ...
                                                          '           %s'], txt);
                        case '.h5'
                            % Update
                            txt = sprintf("          name: %s\n" + ...
                                    "                       type: %s\n" + ...
                                    "                       size: %s\n" + ...
                                    "                datasets: %s\n", ...
                                        row.Name, ...
                                        row.Type, ...
                                        row.Size, ...
                                        row.Datasets);
                            app.TextArea.Value = sprintf(['>>File Selected\n>>info\n' ...
                                                          '           %s'], txt);
                    end
            end
        end

        % Button pushed function: ALLButton
        function ALLButtonPushed(app, event)
            % Select All
            app.ListBox.Value = app.ListBox.Items;
            app.ListBoxValueChanged(app)
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            % Load Selection
            value = app.ListBox.Value;

            %% Check Selection
            % Empty Selection
            if isempty(value)
                new_msg = sprintf(">>No value selected. Try again.");
                app.TextArea.Value = vertcat(app.TextArea.Value, new_msg);
            end

            % Repetitive Selection
            if isequal(value, app.G.Encoding.last_val_load)
                new_msg = sprintf(">>Variable already loaded.\n" + ...
                                  ">>Continue to other tabs for further analysis.");
                app.TextArea.Value = vertcat(app.TextArea.Value, new_msg);
                return
            end

            %% New Table
            % Find rows with matching names
            matchingRows = ismember(app.G.Encoding.files.Name, value);
            
            % Create a new table with matching rows
            newTable = app.G.Encoding.files(matchingRows, :);
            
            % Initialize 
            out = [];
            
            % UIprogressdlg
            d = uiprogressdlg(app.UIFigure,'Title','Loading Selected',...
            'Cancelable','off', 'Indeterminate','on','CancelText','Early Stop');

            %% FILE
            if strcmp(app.G.Encoding.loading_type, 'File')
                % Extension
                [~, ~, extension] = fileparts(app.G.Encoding.data_path);

                %% .MAT
                if strcmp(extension, '.mat')
                    % If nested struct
                    if strcmp(newTable.Class{:}, 'struct') 
                        if numel(fields(app.G.Encoding.mat_struct))==0
                            mat_struct = struct('field_name', {value});
                            app.G.Encoding.mat_struct = mat_struct;
                        else
                            % nested struct within struct. break
                            msg = "Too many nested structures. Select another variable.";
                            app.G.Encoding.flag = app.G.Encoding.flag + msg;
                        end
                        % Re-run file selection
                        app.FolderButtonValueChanged()
                        return
                    end
                    % Load
                    temp = matfile(app.G.Encoding.data_path);
                    % Update UIprogressdlg
                    d.Message = sprintf('Loading "%s"...', value);
                    % Direct load
                    if numel(fields(app.G.Encoding.mat_struct))==0
                        out = temp.(value);
                    else
                        % If struct 1 level up
                        first_lvl = app.G.Encoding.mat_struct.field_name;
                        second_lvl = temp.(first_lvl);
                        out = second_lvl.(value);
                    end
                
                %% .TIF
                elseif strcmp(extension, '.tif')
                    % Update UIprogressdlg
                    d.Message = sprintf('Loading "%s"...', extension);
                    % load selected frames
                    frames = double(newTable.Name');
                    temp = TIFFStack(app.G.Encoding.data_path);
                    out = temp(:,:,frames);
                end
            end

            %% FOLDER
            if strcmp(app.G.Encoding.loading_type, 'Folder')
                % Update UIprogressdlg
                d.Message = sprintf('Loading %d images...', height(newTable));
                
                % Multi-selection
                if height(newTable) > 1
                    % load selected frames
                    out = zeros(newTable.Width(1), newTable.Height(2), height(newTable));
                    base = string(app.G.Encoding.data_path);
                    for k=1:height(newTable)
                        out(:,:,k) = imread(base+"\"+newTable.Name(k));
                    end
                else
                    % Single Selection
                    fileName = string(app.G.Encoding.data_path) + "\" + newTable.Name;
                    [~, ~, extension] = fileparts(fileName);
                    if strcmp(extension, '.tif')
                        % load all tif files
                        out = TIFFStack(char(fileName));    % only takes char in lol
                    else
                        % load selection
                        out = imread(fileName);
                    end
                end
            end
            
            %% Out
            % Close UIProgress
            close(d);

            % Update Selection
            app.G.Data.original = out;
            app.G.Encoding.last_val_load = value;

            % Update TextArea
            new_msg = sprintf(">>Load successful.\n" + ...
                              ">>Continue to other tabs for further analysis.");
            app.TextArea.Value = vertcat(app.TextArea.Value, new_msg);
        end

        % Value changed function: DetectButton
        function DetectButtonValueChanged(app, event)
            value = app.DetectButton.Value;
            if value && ~isempty(app.G.Data.original)
                % UIprogressdlg
                d = uiprogressdlg(app.UIFigure,'Title','Loading',...
                    'Cancelable','off', 'Indeterminate','on');
                % Mean Image Stack Projection
                if isempty(app.G.Data.projection)
                    app.G.Data.projection = mean(app.G.Data.original,3);
                end
                
                % Update Plots
                cla(app.UIAxes, "reset"); cla(app.UIAxes_2, "reset");
                % mean image stack
                imagesc(app.G.Data.projection, 'Parent', app.UIAxes_2);
                set(app.UIAxes_2, 'XTick', [], 'YTick', [])
                % large default plot
                imagesc(app.G.Data.original(:,:,1), 'Parent', app.UIAxes);
                % colormap
                colormap(app.UIAxes, gray); colormap(app.UIAxes_2, gray);
                % Close UIprogress
                close(d);
            end

            % Visibilities
            app.UIAxes.Visible = 'on';
            app.MeanImageStackProjectionPanel.Visible = 'on';
            app.GridLayout21.Visible = 'on';

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GraFTAnalysisLabel = uilabel(app.GridLayout);
            app.GraFTAnalysisLabel.Text = 'GraFT - Analysis';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.NewLoadTab = uitab(app.TabGroup);
            app.NewLoadTab.Title = 'New Load';
            app.GridLayout2 = uigridlayout(app.NewLoadTab);
            app.LoadDataPanel = uipanel(app.GridLayout2);
            app.LoadDataPanel.Title = 'Load Data';
            app.GridLayout4 = uigridlayout(app.LoadDataPanel);
            app.Panel_5 = uipanel(app.GridLayout4);
            app.GridLayout10 = uigridlayout(app.Panel_5);
            app.TextArea = uitextarea(app.GridLayout10);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'>>'; ''};
            app.ListBox = uilistbox(app.GridLayout10);
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {'Item 1'};
            app.GridLayout11 = uigridlayout(app.GridLayout10);
            app.SelectButton = uibutton(app.GridLayout11, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.ALLButton = uibutton(app.GridLayout11, 'push');
            app.ALLButton.ButtonPushedFcn = createCallbackFcn(app, @ALLButtonPushed, true);
            app.ALLButton.Text = 'ALL';
            app.ShiftClickGroupSelectCtrlClickMultiSelectLabel = uilabel(app.GridLayout10);
            app.ShiftClickGroupSelectCtrlClickMultiSelectLabel.Text = {'Shift + Click = Group Select'; 'Ctrl + Click = Multi-Select'};
            app.SelectFileTypePanel = uipanel(app.GridLayout4);
            app.SelectFileTypePanel.Title = 'Select File Type';
            app.GridLayout6 = uigridlayout(app.SelectFileTypePanel);
            app.FolderButton = uibutton(app.GridLayout6, 'state');
            app.FolderButton.ValueChangedFcn = createCallbackFcn(app, @FolderButtonValueChanged, true);
            app.FolderButton.Text = 'Folder';
            app.FileButton = uibutton(app.GridLayout6, 'state');
            app.FileButton.ValueChangedFcn = createCallbackFcn(app, @FolderButtonValueChanged, true);
            app.FileButton.Text = 'File';
            app.S3Button = uibutton(app.GridLayout6, 'state');
            app.S3Button.ValueChangedFcn = createCallbackFcn(app, @FolderButtonValueChanged, true);
            app.S3Button.Enable = 'off';
            app.S3Button.Text = 'S3';
            app.LocalfolderLabel = uilabel(app.GridLayout6);
            app.LocalfolderLabel.Text = 'Local folder';
            app.IndividualfileLabel = uilabel(app.GridLayout6);
            app.IndividualfileLabel.Text = 'Individual file';
            app.GRAFTObjectLabel = uilabel(app.GridLayout6);
            app.GRAFTObjectLabel.Text = 'GRAFT Object';
            app.RemotedataLabel = uilabel(app.GridLayout6);
            app.RemotedataLabel.Text = 'Remote data';
            app.GRAFTButton = uibutton(app.GridLayout6, 'state');
            app.GRAFTButton.Enable = 'off';
            app.GRAFTButton.Text = 'GRAFT';
            app.Panel_2 = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.PATHLabel = uilabel(app.GridLayout3);
            app.PATHLabel.Text = 'PATH:';
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = 'C:\Users\estra\Documents\MATLAB\GraFT App 623';
            app.ResetButton = uibutton(app.GridLayout3, 'push');
            app.ResetButton.Visible = 'off';
            app.ResetButton.Text = 'Reset';
            app.Panel_4 = uipanel(app.GridLayout3);
            app.GridLayout5 = uigridlayout(app.Panel_4);
            app.NewLoadButton = uibutton(app.GridLayout5, 'push');
            app.NewLoadButton.ButtonPushedFcn = createCallbackFcn(app, @NewLoadButtonPushed, true);
            app.NewLoadButton.Enable = 'off';
            app.NewLoadButton.Text = 'New Load';
            app.WelcometoGraFTSelectfiletypebelowtobeginLabel = uilabel(app.GridLayout5);
            app.WelcometoGraFTSelectfiletypebelowtobeginLabel.Text = 'Welcome to GraFT! Select file type below to begin.';
            app.PreprocessTab = uitab(app.TabGroup);
            app.PreprocessTab.Title = 'Preprocess';
            app.GridLayout7 = uigridlayout(app.PreprocessTab);
            app.GridLayout16 = uigridlayout(app.GridLayout7);
            app.UIAxes = uiaxes(app.GridLayout16);
            app.ViewModePanel = uipanel(app.GridLayout7);
            app.ViewModePanel.Title = 'View Mode';
            app.GridLayout17 = uigridlayout(app.ViewModePanel);
            app.MeanImageStackProjectionPanel = uipanel(app.GridLayout17);
            app.MeanImageStackProjectionPanel.Title = 'Mean Image Stack Projection';
            app.GridLayout18 = uigridlayout(app.MeanImageStackProjectionPanel);
            app.UIAxes_2 = uiaxes(app.GridLayout18);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = {};
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YTickLabel = {};
            app.GridLayout19 = uigridlayout(app.GridLayout17);
            app.GridLayout20 = uigridlayout(app.GridLayout19);
            app.DetectButton = uibutton(app.GridLayout20, 'state');
            app.DetectButton.ValueChangedFcn = createCallbackFcn(app, @DetectButtonValueChanged, true);
            app.DetectButton.Text = 'Detect';
            app.NewLoadLabel = uilabel(app.GridLayout20);
            app.NewLoadLabel.Text = 'New Load';
            app.GridLayout21 = uigridlayout(app.GridLayout19);
            app.IndividualimageviewLabel = uilabel(app.GridLayout21);
            app.IndividualimageviewLabel.Text = 'Individual image view';
            app.SingleViewButton = uibutton(app.GridLayout21, 'push');
            app.SingleViewButton.Text = 'Single View';
            app.CropButton = uibutton(app.GridLayout21, 'push');
            app.CropButton.Text = 'Crop';
            app.MaskButton = uibutton(app.GridLayout21, 'push');
            app.MaskButton.Text = 'Mask';
            app.InteractiveselectionofareasquareregiontocropLabel = uilabel(app.GridLayout21);
            app.InteractiveselectionofareasquareregiontocropLabel.Text = 'Interactive selection of area (square region) to crop';
            app.AddinsertselectprecomputedmaskoptionsLabel = uilabel(app.GridLayout21);
            app.AddinsertselectprecomputedmaskoptionsLabel.Text = 'Add / insert / select pre-computed mask options';
            app.HistogramButton = uibutton(app.GridLayout21, 'push');
            app.HistogramButton.Text = 'Histogram';
            app.ContrasttoolviewingonlyLabel = uilabel(app.GridLayout21);
            app.ContrasttoolviewingonlyLabel.Text = {'Contrast tool '; '(viewing only)'};
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.GridLayout12 = uigridlayout(app.ParametersTab);
            app.PreLoadPanel = uipanel(app.GridLayout12);
            app.PreLoadPanel.Title = 'Pre-Load';
            app.StandardPanel = uipanel(app.GridLayout12);
            app.StandardPanel.Title = 'Standard';
            app.AdvancedPanel = uipanel(app.GridLayout12);
            app.AdvancedPanel.Title = 'Advanced';
            app.GraFTTab = uitab(app.TabGroup);
            app.GraFTTab.Title = 'GraFT!';
            app.GridLayout8 = uigridlayout(app.GraFTTab);
            app.TabGroup2 = uitabgroup(app.GridLayout8);
            app.RunTab = uitab(app.TabGroup2);
            app.RunTab.Title = 'Run';
            app.GridLayout14 = uigridlayout(app.RunTab);
            app.ResultsTab = uitab(app.TabGroup2);
            app.ResultsTab.Title = 'Results';
            app.GridLayout15 = uigridlayout(app.ResultsTab);
            app.AnalysisTab_2 = uitab(app.TabGroup);
            app.AnalysisTab_2.Title = 'Analysis';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.GridLayout9 = uigridlayout(app.SettingsTab);
            app.Label2 = uilabel(app.GridLayout);
            app.Label2.Text = 'The details of GraFT are described in: A.S. Charles, N. Cermak, R. Affan, B. Scott, J. Schiller & G. Mishne. GraFT: Graph Filtered Temporal Dictionary Learning for Functional Neural Imaging.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraFT2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end